classdef CFS_Crafter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        HelpButton                    matlab.ui.control.Button
        ClicktoEnterModulePanel       matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        ModificationButton            matlab.ui.control.Button
        AnalysisButton                matlab.ui.control.Button
        ConversionButton              matlab.ui.control.Button
        CreationButton                matlab.ui.control.Button
        CFSCrafterLabel               matlab.ui.control.Label
        EnterScreenInformationPanel   matlab.ui.container.Panel
        GridLayout2                   matlab.ui.container.GridLayout
        HeightCmEditField             matlab.ui.control.EditField
        HeightCmEditFieldLabel        matlab.ui.control.Label
        WidthCmEditField              matlab.ui.control.EditField
        WidthCmEditFieldLabel         matlab.ui.control.Label
        HeightPixelEditField          matlab.ui.control.EditField
        HeightPixelEditFieldLabel     matlab.ui.control.Label
        WidthPixelEditField           matlab.ui.control.EditField
        WidthPixelEditFieldLabel      matlab.ui.control.Label
        ViewingDistancecmEditField    matlab.ui.control.EditField
        ViewingDistancecmEditFieldLabel  matlab.ui.control.Label
        ScreenRefreshRateHzEditField  matlab.ui.control.EditField
        ScreenRefreshRateHzEditFieldLabel  matlab.ui.control.Label
        ScreenDimensioncmLabel        matlab.ui.control.Label
        ScreenResolutionPixelLabel    matlab.ui.control.Label
    end

    properties (Access = public)
        screen_info % All screen information
    end

    properties (Access = private)
        creation_app % Description
        modification_app
        analysis_app
        conversion_app
        information_app
    end


    methods (Access = public)

        function [stimuli_property,bw_edges] = convert_mask_info(app,screen_info,stimuli_property,stimuli_array,label,varargin)

            [stimuli_property.stimuli_height_pixel,stimuli_property.stimuli_width_pixel,color_layer,stimuli_property.total_frames] = size(stimuli_array);

            if label == "cfs_crafter_image"
                
                stimuli_property.sequence_duration = 1./screen_info.refresh_rate;
                stimuli_property.update_rate = nan;
            end

            if nargin == 5
                edge_detection_method = "canny";
                edge_detection_threshold = [];
            elseif nargin == 7
                edge_detection_method = varargin{1};
                edge_detection_threshold = varargin{2};
            end

            stimuli_property.stimuli_width_degree = app.pix2degree(stimuli_property.stimuli_width_pixel,screen_info.screen_width_cm,screen_info.screen_width_pixel,screen_info.viewing_distance);
            stimuli_property.stimuli_height_degree = app.pix2degree(stimuli_property.stimuli_height_pixel,screen_info.screen_height_cm,screen_info.screen_height_pixel,screen_info.viewing_distance);


            if color_layer == 3
                stimuli_property.color = "rgb";
            else
                stimuli_property.color = "grayscale";
            end

            gray_stimuli_array = app.convert_to_grayscale(stimuli_array);
            entropy_frame = zeros(stimuli_property.total_frames,1);

            for i = 1:stimuli_property.total_frames
                entropy_frame(i) = entropy(gray_stimuli_array(:,:,:,i));

            end

            stimuli_property.entropy = mean(entropy_frame,"all");
            stimuli_property.mean_luminance = mean(gray_stimuli_array,"all");
            stimuli_property.rms_contrast = mean(std(gray_stimuli_array,1,[1,2]));

            bw_edges = find_edge(app,stimuli_array,edge_detection_method,edge_detection_threshold);

            stimuli_property.edge_density =  bw_edges.edge_density;
            stimuli_property.edge_detection_threshold = bw_edges.edge_detection_threshold;
            stimuli_property.edge_detection_method = bw_edges.edge_detection_method;
        end


        function mask_size_degree = pix2degree(app,size_pix,screen_size_cm,screen_resolution_pix,screen_distance_cm)

            %pixel size in cm/pix
            pix_size = screen_size_cm/screen_resolution_pix;

            %mask size in cm
            mask_size_cm = pix_size * size_pix;

            %mask size in degree of visual angle
            mask_size_degree = 2 * rad2deg(atan(mask_size_cm/(2 * screen_distance_cm)));
        end

        function bw_edges = find_edge(app,stimuli_array,edge_detection_method,edge_detection_threshold)

            bw_edges.edge_detection_method = edge_detection_method;

            [~,~,~,frame] = size(stimuli_array);

            gray_stimuli_array = app.convert_to_grayscale(stimuli_array);

            if isnan(edge_detection_threshold)
                for i = 1:frame
                    [bw_edges.edge_array(:,:,i),bw_edges.edge_detection_threshold] = edge(gray_stimuli_array(:,:,:,i),edge_detection_method);
                end
            else

                for i = 1:frame
                    [bw_edges.edge_array(:,:,i),bw_edges.edge_detection_threshold] = edge(gray_stimuli_array(:,:,:,i),edge_detection_method,edge_detection_threshold);
                end
            end
            bw_edges.edge_density = mean(bw_edges.edge_array,'all');
        end


        function stimuli_array = set_RMS_and_luminance(~,stimuli_array,target_RMS_contrast,target_mean_luminance)

            [~,~,color,~] = size(stimuli_array);

            if color == 1
                gray_stimuli_array = stimuli_array;
            else
                gray_stimuli_array = 0.2989 * stimuli_array(:,:,1,:) + 0.5870 * stimuli_array(:,:,2,:) + 0.1140 * stimuli_array(:,:,3,:);

            end

            image_mean = mean(gray_stimuli_array,[1,2]);
            mean_rms_contrast = mean(std(gray_stimuli_array,1,[1,2]));
            %             mean_rms_contrast = mean(std(gray_stimuli_array,1,1));

            stimuli_array = (stimuli_array-image_mean)./ mean_rms_contrast * target_RMS_contrast;

            stimuli_array = stimuli_array + target_mean_luminance;

            stimuli_array(stimuli_array>1) = 1;
            stimuli_array(stimuli_array<0) = 0;

        end

        function gray_stimuli_array = convert_to_grayscale(app,stimuli_array)
            [~,~,color,~] = size(stimuli_array);
            if color == 1
                gray_stimuli_array = stimuli_array;
            else
                gray_stimuli_array = 0.2989 * stimuli_array(:,:,1,:) + 0.5870 * stimuli_array(:,:,2,:) + 0.1140 * stimuli_array(:,:,3,:);
            end
        end


        function  enable_screen_info_edit(app)
            app.ScreenRefreshRateHzEditField.Editable = "on";
            app.ViewingDistancecmEditField.Editable = "on";
            app.WidthPixelEditField.Editable = "on";
            app.HeightPixelEditField.Editable = "on";
            app.WidthCmEditField.Editable = "on";
            app.HeightCmEditField.Editable = "on";

            app.CreationButton.Enable = "on";
            app.ConversionButton.Enable = "on";
            app.ModificationButton.Enable = "on";
            app.AnalysisButton.Enable = "on";
        end

        function return_flag = confirm_rms_alert(~,calling_app,rms_contrast)
            return_flag = 0;
            if rms_contrast>= 0.2
                selection = uiconfirm(calling_app.UIFigure,...
                    "Large RMS contrast might results in clipping of the image. Press OK if you want to proceed with the current RMS contrast.",...
                    "Input Warning", ...
                    'DefaultOption',1,'CancelOption',2);

                if selection == "Cancel"
                    return_flag = 1;
                end
            end
        end


    end

    methods (Access = private)
        function [screen_info,return_status] = get_screen_info(app)
            screen_info.refresh_rate = str2num(app.ScreenRefreshRateHzEditField.Value);
            screen_info.viewing_distance = str2num(app.ViewingDistancecmEditField.Value);
            screen_info.screen_width_cm = str2num(app.WidthCmEditField.Value);
            screen_info.screen_height_cm = str2num(app.HeightCmEditField.Value);
            screen_info.screen_width_pixel = str2num(app.WidthPixelEditField.Value);
            screen_info.screen_height_pixel = str2num(app.HeightPixelEditField.Value);

            if  any(structfun(@isempty, screen_info)) || any(structfun(@app.isNonPosInt, screen_info))

                uialert(app.UIFigure,...
                    "Screen information is not complete or not in the correct format (Only positive numbers are allowed)!",...
                    "Input Error",...
                    "Icon","error");

                return_status = 1;
            else
                return_status = 0;
                app.ScreenRefreshRateHzEditField.Editable = "off";
                app.ViewingDistancecmEditField.Editable = "off";
                app.WidthPixelEditField.Editable = "off";
                app.HeightPixelEditField.Editable = "off";
                app.WidthCmEditField.Editable = "off";
                app.HeightCmEditField.Editable = "off";

                app.CreationButton.Enable = "off";
                app.ConversionButton.Enable = "off";
                app.ModificationButton.Enable = "off";
                app.AnalysisButton.Enable = "off";
            end

        end


        function results = isNonPosInt(~,n)
            results = n < 1;
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            screen_size = get(0,'ScreenSize');
            screen_pixel_per_inch = get(0,'ScreenPixelsPerInch');
            app.WidthPixelEditField.Value = num2str(screen_size(3));
            app.HeightPixelEditField.Value = num2str(screen_size(4));
            app.WidthCmEditField.Value =  num2str(round(((screen_size(3) / screen_pixel_per_inch) * 2.54),1)); % 1 inch = 2.54 cm
            app.HeightCmEditField.Value =  num2str(round(((screen_size(4) / screen_pixel_per_inch) * 2.54),1));

        end

        % Button pushed function: CreationButton
        function CreationButtonPushed(app, event)
            [app.screen_info,return_status] = get_screen_info(app);

            if return_status
                return
            else
                app.creation_app = creation(app);
            end


        end

        % Button pushed function: ConversionButton
        function ConversionButtonPushed(app, event)
            [app.screen_info,return_status] = get_screen_info(app);

            if return_status
                return
            else
                app.conversion_app = conversion(app);
            end

        end

        % Button pushed function: ModificationButton
        function ModificationButtonPushed(app, event)
            [app.screen_info,return_status] = get_screen_info(app);

            if return_status
                return
            else
                app.modification_app = modification(app);
            end
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            [app.screen_info,return_status] = get_screen_info(app);

            if return_status
                return
            else
                app.analysis_app = analysis(app);
            end

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.information_app = information("Main.html");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
            delete(app.creation_app)
            delete(app.conversion_app)
            delete(app.modification_app)
            delete(app.analysis_app)
            delete(app.information_app)
            catch
            end
            delete(app)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.EnterScreenInformationPanel = uipanel(app.GridLayout);
            app.EnterScreenInformationPanel.Title = 'Enter Screen Information';
            app.GridLayout2 = uigridlayout(app.EnterScreenInformationPanel);
            app.ScreenResolutionPixelLabel = uilabel(app.GridLayout2);
            app.ScreenResolutionPixelLabel.Text = 'Screen Resolution (Pixel)';
            app.ScreenDimensioncmLabel = uilabel(app.GridLayout2);
            app.ScreenDimensioncmLabel.Text = 'Screen Dimension (cm)';
            app.ScreenRefreshRateHzEditFieldLabel = uilabel(app.GridLayout2);
            app.ScreenRefreshRateHzEditFieldLabel.Text = 'Screen Refresh Rate (Hz)';
            app.ScreenRefreshRateHzEditField = uieditfield(app.GridLayout2, 'text');
            app.ViewingDistancecmEditFieldLabel = uilabel(app.GridLayout2);
            app.ViewingDistancecmEditFieldLabel.Text = 'Viewing Distance (cm)';
            app.ViewingDistancecmEditField = uieditfield(app.GridLayout2, 'text');
            app.WidthPixelEditFieldLabel = uilabel(app.GridLayout2);
            app.WidthPixelEditFieldLabel.Text = 'Width';
            app.WidthPixelEditField = uieditfield(app.GridLayout2, 'text');
            app.HeightPixelEditFieldLabel = uilabel(app.GridLayout2);
            app.HeightPixelEditFieldLabel.Text = 'Height';
            app.HeightPixelEditField = uieditfield(app.GridLayout2, 'text');
            app.WidthCmEditFieldLabel = uilabel(app.GridLayout2);
            app.WidthCmEditFieldLabel.Text = 'Width';
            app.WidthCmEditField = uieditfield(app.GridLayout2, 'text');
            app.HeightCmEditFieldLabel = uilabel(app.GridLayout2);
            app.HeightCmEditFieldLabel.Text = 'Height';
            app.HeightCmEditField = uieditfield(app.GridLayout2, 'text');
            app.CFSCrafterLabel = uilabel(app.GridLayout);
            app.CFSCrafterLabel.Text = 'CFS Crafter';
            app.ClicktoEnterModulePanel = uipanel(app.GridLayout);
            app.ClicktoEnterModulePanel.Title = 'Click to Enter Module';
            app.GridLayout3 = uigridlayout(app.ClicktoEnterModulePanel);
            app.CreationButton = uibutton(app.GridLayout3, 'push');
            app.CreationButton.ButtonPushedFcn = createCallbackFcn(app, @CreationButtonPushed, true);
            app.CreationButton.Text = 'Creation';
            app.ConversionButton = uibutton(app.GridLayout3, 'push');
            app.ConversionButton.ButtonPushedFcn = createCallbackFcn(app, @ConversionButtonPushed, true);
            app.ConversionButton.Text = 'Conversion';
            app.AnalysisButton = uibutton(app.GridLayout3, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.ModificationButton = uibutton(app.GridLayout3, 'push');
            app.ModificationButton.ButtonPushedFcn = createCallbackFcn(app, @ModificationButtonPushed, true);
            app.ModificationButton.Text = 'Modification';
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CFS_Crafter

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end