classdef analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        HelpButton                   matlab.ui.control.Button
        PreviewEdgesCheckBox         matlab.ui.control.CheckBox
        DeleteSelectedStimuliButton  matlab.ui.control.Button
        AnalysistobedonePanel        matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        BasicInformationLabel        matlab.ui.control.Label
        EdgeDetectionThresholdEditField  matlab.ui.control.EditField
        EdgeDetectionThresholdEditField_2Label  matlab.ui.control.Label
        EdgeDetectionMethodDropDown  matlab.ui.control.DropDown
        EdgeDetectionMethodLabel     matlab.ui.control.Label
        ColorCheckBox                matlab.ui.control.CheckBox
        SpatialTemporalFrequencyCheckBox  matlab.ui.control.CheckBox
        ListofSitmuliLabel           matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        StartButton                  matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        UITable                      matlab.ui.control.Table
        AddStimuliButton             matlab.ui.control.Button
        StimuliAnalysisLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        main_app
        edge_preview_app
        display_analysis_results_app
        information_app

        stimuli_list
        current_stimuli
        alphabet

        % Description
    end

    properties (Access = public)
        % Description
        app_id % Description
        analysis_items
        
        edge_detection_method
        edge_detection_threshold
    end

    methods (Access = private)
        function [spatial_frequency,spatial_psd] = get_spatial_amp(app,stimuli)

            [row,col,color,frames] = size(stimuli.stimuli_array);

            gray_stimuli_array = app.convert_to_grayscale(stimuli.stimuli_array);

            %max dimension between half column and half row
            max_dim = ceil(max(row/2,col/2));

            %padded to a square
            fft_gray_stimuli = fftshift(fft2(gray_stimuli_array,2*max_dim,2*max_dim));

            power = abs(fft_gray_stimuli./((2.*max_dim).^2)).^2 ;
            %radial average


            [XX,YY] = meshgrid(-max_dim:max_dim-1,-max_dim:1:max_dim-1);

            spatial_map = round(sqrt((XX).^2 + (YY).^2)); % distance map

            max_rad = max(max(spatial_map));

            spatial_psd = zeros(max_rad,1);

            for i = 1:max_rad
                index = spatial_map == i;
                spatial_psd(i) = sum(power(index),'all')./sum(index,'all');
            end


            spatial_frequency = (0:max_rad-1)';
            %use the degree dimension of the padded square
            spatial_frequency = spatial_frequency./ max(stimuli.stimuli_property.stimuli_width_degree,stimuli.stimuli_property.stimuli_height_degree);

        end

        function [temporal_frequency,temporal_psd] = get_temporal_amp(app,stimuli)

            [row,col,color,frames] = size(stimuli.stimuli_array);

            gray_stimuli_array = app.convert_to_grayscale(stimuli.stimuli_array);

            %             temporal_fft_array = fft(gray_stimuli_array,[],4);
            temporal_fft_array = fft(gray_stimuli_array,2^nextpow2(frames),4);
            temporal_psd = squeeze(mean(abs(temporal_fft_array),[1,2]));
            temporal_psd = temporal_psd(1:end/2 + 1);
            temporal_psd(2:end-1) = 2.*temporal_psd(2:end-1);

            temporal_frequency = (0:2^nextpow2(frames)/2)';
            temporal_frequency = temporal_frequency./stimuli.stimuli_property.sequence_duration;
        end

        function gray_stimuli_array = convert_to_grayscale(app,stimuli_array)
            [~,~,color,~] = size(stimuli_array);
            if color == 1
                gray_stimuli_array = stimuli_array;
            else
                gray_stimuli_array = 0.2989 * stimuli_array(:,:,1,:) + 0.5870 * stimuli_array(:,:,2,:) + 0.1140 * stimuli_array(:,:,3,:);
            end
        end

        function edge_threshold_alert(app)
            uialert(app.UIFigure,...
                "Invalid input to edge detection threshold!",...
                "Input Error",...
                "Icon","error");
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.main_app = main_app;

            app.alphabet = ["A" "B" "C" "D" "E" "F" "G" "H" "I" "J" "K" "L" "M" "N" "O" "P" "Q" "R" "S" "T" "U" "V" "W" "X" "Y" "Z"];

            app.current_stimuli = 1;

            app.edge_detection_method = app.EdgeDetectionMethodDropDown.Value;
            app.edge_detection_threshold = str2double(app.EdgeDetectionThresholdEditField.Value);

            app.stimuli_list = table();
        end

        % Button pushed function: AddStimuliButton
        function AddStimuliButtonPushed(app, event)
            [file,path] = uigetfile('*.mat;*.jpg;*.jpeg;*.png',...
                'Select Stimuli to be Analyse', ...
                'MultiSelect', 'on');


            if isequal(file,0)
                return
            elseif isequal(class(file),'char')
                app.stimuli_list(app.current_stimuli,'Symbol') = {app.alphabet(app.current_stimuli)};

                [~,~,extension] = fileparts(file);
                switch extension
                    case '.mat'
                        app.stimuli_list(app.current_stimuli,'Type') =  {"MAT File"};
                    otherwise
                        app.stimuli_list(app.current_stimuli,'Type') =  {"Image"};
                end


                app.stimuli_list(app.current_stimuli,'Filepath') = {path};

                app.stimuli_list(app.current_stimuli,'Filename') = {file};

                app.stimuli_list(app.current_stimuli,'Selection') = {false};

                app.current_stimuli = height(app.stimuli_list) + 1;
            else
                for i = 1:length(file)

                    app.stimuli_list(app.current_stimuli,'Symbol') = {app.alphabet(app.current_stimuli)};

                    [~,~,extension] = fileparts(file(i));
                    switch extension
                        case '.mat'
                            app.stimuli_list(app.current_stimuli,'Type') =  {"MAT File"};
                        otherwise
                            app.stimuli_list(app.current_stimuli,'Type') =  {"Image"};
                    end

                    app.stimuli_list(app.current_stimuli,'Filepath') = {path};

                    app.stimuli_list(app.current_stimuli,'Filename') = {file(i)};

                    app.stimuli_list(app.current_stimuli,'Selection') = {false};

                    app.current_stimuli = height(app.stimuli_list) + 1;

                end
            end


            app.UITable.Data = app.stimuli_list;

            app.UITable.ColumnEditable = [false false false false true];

        end

        % Button pushed function: DeleteSelectedStimuliButton
        function DeleteSelectedStimuliButtonPushed(app, event)
            row_index = app.UITable.Data.Selection;

            app.stimuli_list(row_index,:)= [];

            for i = 1:height(app.stimuli_list)
                app.stimuli_list(i,'Symbol') =  {app.alphabet(i)};
            end

            app.UITable.Data = app.stimuli_list;

            app.current_stimuli = height(app.stimuli_list) + 1;
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.UITable.Data  = [];
            app.current_stimuli = 1;
            app.stimuli_list = table();
            

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            progress = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Starting...');


            if isempty(app.stimuli_list)
                uialert(app.UIFigure,...
                    "No File!",...
                    "File Error",...
                    "Icon","error");

                close(progress)
                return
            end

            progress.Value  = .1;

            progress.Message = 'Checking Input Parameters...';

            clear app.analysis_result

            app.analysis_items.frequency = app.SpatialTemporalFrequencyCheckBox.Value;
            app.analysis_items.color = app.ColorCheckBox.Value;

            app.edge_detection_method = app.EdgeDetectionMethodDropDown.Value;
            [app.edge_detection_threshold,num_flag] = str2num(app.EdgeDetectionThresholdEditField.Value);

            if ~isempty(app.EdgeDetectionThresholdEditField.Value) %check if the entry is empty
                if num_flag
                    switch app.edge_detection_method
                        case {"Sobel", "log"}

                            if length(app.edge_detection_threshold) > 1
                                app.edge_threshold_alert
                                close(progress)
                                return
                            end

                        otherwise
                            if length(app.edge_detection_threshold) == 1
                                if app.edge_detection_threshold <0 || app.edge_detection_threshold >=1
                                    app.edge_threshold_alert
                                    close(progress)
                                    return
                                end
                            elseif length(app.edge_detection_threshold) == 2
                                if app.edge_detection_threshold(1) <0 || app.edge_detection_threshold(2) >= 1 || app.edge_detection_threshold(1) > app.edge_detection_threshold(2)
                                    app.edge_threshold_alert
                                    close(progress)
                                    return
                                end
                            else
                                app.edge_threshold_alert
                                close(progress)
                                return
                            end

                    end
                else
                    app.edge_threshold_alert
                    close(progress)
                    return
                end
            end

            progress.Value  = .2;

            progress.Message = 'Loading Stimuli...';

            for i = 1:height(app.UITable.Data)

                if app.stimuli_list.Type(i) == "MAT File"
                    temp = load(fullfile(app.stimuli_list.Filepath{i},app.stimuli_list.Filename{i}));
                    if isfield(temp,'stimuli')
                        all_stimuli(i) = temp.stimuli;
                    else
                        uialert(app.UIFigure,...
                            "One of the selected .mat file is not created by CFS-Crafter!",...
                            "File Error",...
                            "Icon","error");
                        close(progress)
                        return
                    end
                else
                    all_stimuli(i).stimuli_array = im2double(imread(fullfile(app.stimuli_list.Filepath{i},app.stimuli_list.Filename{i})));

                    all_stimuli(i).label = "cfs_crafter_image";
                    all_stimuli(i).screen_info = app.main_app.screen_info;
                    all_stimuli(i).stimuli_property = [];
                    all_stimuli(i).inividual_masks = all_stimuli(i).stimuli_array;


                end

                [all_stimuli(i).stimuli_property,bw_edges(i)] = app.main_app.convert_mask_info(all_stimuli(i).screen_info,all_stimuli(i).stimuli_property,all_stimuli(i).stimuli_array, all_stimuli(i).label, app.edge_detection_method, app.edge_detection_threshold);
                %check the label to distinguish masks & target

                analysis_results(i).basic_info.symbol = app.stimuli_list.Symbol(i);
                analysis_results(i).basic_info.color = all_stimuli(i).stimuli_property.color;
                analysis_results(i).basic_info.total_frames = all_stimuli(i).stimuli_property.total_frames;
                analysis_results(i).basic_info.entropy = all_stimuli(i).stimuli_property.entropy;
                analysis_results(i).basic_info.mean_luminance = all_stimuli(i).stimuli_property.mean_luminance;
                analysis_results(i).basic_info.rms_contrast= all_stimuli(i).stimuli_property.rms_contrast;
                analysis_results(i).basic_info.edge_density = all_stimuli(i).stimuli_property.edge_density;
            end


            progress.Value = .3;

            if app.analysis_items.frequency

                progress.Message = 'Analysing Spatial & Temporal Frequency...';

                for i = 1:height(app.UITable.Data)

                    [analysis_results(i).frequency_results.spatial_frequency.frequency,analysis_results(i).frequency_results.spatial_frequency.psd] = app.get_spatial_amp(all_stimuli(i));

                    [analysis_results(i).frequency_results.temporal_frequency.frequency,analysis_results(i).frequency_results.temporal_frequency.psd]  = app.get_temporal_amp(all_stimuli(i));
                end

            end


            progress.Value = .4;

            if app.analysis_items.color

                progress.Message = 'Analysing HSV color content...';

                for i = 1:height(app.UITable.Data)
                    if analysis_results(i).basic_info.color == "rgb"
                        for j = 1:all_stimuli(i).stimuli_property.total_frames
                            analysis_results(i).hsv_results.hsv_array(:,:,:,j) = rgb2hsv(all_stimuli(i).stimuli_array(:,:,:,j));
                        end
                        [analysis_results(i).hsv_results.hue.bin_counts, analysis_results(i).hsv_results.hue.bin_edges] = histcounts(analysis_results(i).hsv_results.hsv_array(:,:,1,:),20,"Normalization","probability");
                        [analysis_results(i).hsv_results.saturation.bin_counts, analysis_results(i).hsv_results.saturation.bin_edges] = histcounts(analysis_results(i).hsv_results.hsv_array(:,:,2,:),20,"Normalization","probability");
                        [analysis_results(i).hsv_results.value.bin_counts, analysis_results(i).hsv_results.value.bin_edges] = histcounts(analysis_results(i).hsv_results.hsv_array(:,:,3,:),20,"Normalization","probability");

                        analysis_results(i).hsv_results.hue.summary.mean = mean(analysis_results(i).hsv_results.hsv_array(:,:,1,:),"all");
                        analysis_results(i).hsv_results.hue.summary.median = median(analysis_results(i).hsv_results.hsv_array(:,:,1,:),"all");
                        analysis_results(i).hsv_results.hue.summary.mode = mode(analysis_results(i).hsv_results.hsv_array(:,:,1,:),"all");
                        analysis_results(i).hsv_results.hue.summary.range = [min(analysis_results(i).hsv_results.hsv_array(:,:,1,:),[],"all"), max(analysis_results(i).hsv_results.hsv_array(:,:,1,:),[],"all")];

                        analysis_results(i).hsv_results.saturation.summary.mean = mean(analysis_results(i).hsv_results.hsv_array(:,:,2,:),"all");
                        analysis_results(i).hsv_results.saturation.summary.median = median(analysis_results(i).hsv_results.hsv_array(:,:,2,:),"all");
                        analysis_results(i).hsv_results.saturation.summary.mode = mode(analysis_results(i).hsv_results.hsv_array(:,:,2,:),"all");
                        analysis_results(i).hsv_results.saturation.summary.range = [min(analysis_results(i).hsv_results.hsv_array(:,:,2,:),[],"all"), max(analysis_results(i).hsv_results.hsv_array(:,:,2,:),[],"all")];

                        analysis_results(i).hsv_results.value.summary.mean = mean(analysis_results(i).hsv_results.hsv_array(:,:,3,:),"all");
                        analysis_results(i).hsv_results.value.summary.median = median(analysis_results(i).hsv_results.hsv_array(:,:,3,:),"all");
                        analysis_results(i).hsv_results.value.summary.mode = mode(analysis_results(i).hsv_results.hsv_array(:,:,3,:),"all");
                        analysis_results(i).hsv_results.value.summary.range = [min(analysis_results(i).hsv_results.hsv_array(:,:,2,:),[],"all"), max(analysis_results(i).hsv_results.hsv_array(:,:,2,:),[],"all")];
                    end
                    progress.Value = .5 + (i/height(app.UITable.Data) * .3);
                end
            end

            progress.Value = .8;


            if app.PreviewEdgesCheckBox.Value

                for i = 1:length(bw_edges)
                    bw_edges(i).symbol = app.stimuli_list.Symbol{i};
                    bw_edges(i).file = app.stimuli_list.Filename{i};
                    bw_edges(i).path = app.stimuli_list.Filepath{i};
                end

                order = {'symbol','path','file','edge_detection_method','edge_detection_threshold','edge_density','edge_array'};
                bw_edges = orderfields(bw_edges,order);
                app.edge_preview_app = edge_preview(app,app.main_app,bw_edges,all_stimuli);
            end


            progress.Value = 1;
            progress.Message = "Finishing...";

            %edge_preview
            app.display_analysis_results_app =  display_analysis_results(app,analysis_results);


            close(progress)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.main_app.enable_screen_info_edit
                delete(app.edge_preview_app);
                delete(app.display_analysis_results_app);
                delete(app.information_app);

            catch
            end

            delete(app)

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.information_app = information("Analysis.html");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.StimuliAnalysisLabel = uilabel(app.GridLayout);
            app.StimuliAnalysisLabel.Text = 'Stimuli Analysis';
            app.AddStimuliButton = uibutton(app.GridLayout, 'push');
            app.AddStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @AddStimuliButtonPushed, true);
            app.AddStimuliButton.Text = 'Add Stimuli';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Symbol'; 'File Type'; 'File Path'; 'File Name'; 'Selection'};
            app.UITable.RowName = {};
            app.ResetButton = uibutton(app.GridLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '';
            app.ListofSitmuliLabel = uilabel(app.GridLayout);
            app.ListofSitmuliLabel.Text = 'List of Sitmuli';
            app.AnalysistobedonePanel = uipanel(app.GridLayout);
            app.AnalysistobedonePanel.Title = 'Analysis to be done';
            app.GridLayout2 = uigridlayout(app.AnalysistobedonePanel);
            app.SpatialTemporalFrequencyCheckBox = uicheckbox(app.GridLayout2);
            app.SpatialTemporalFrequencyCheckBox.Text = 'Spatial/Temporal Frequency';
            app.ColorCheckBox = uicheckbox(app.GridLayout2);
            app.ColorCheckBox.Text = 'Color';
            app.EdgeDetectionMethodLabel = uilabel(app.GridLayout2);
            app.EdgeDetectionMethodLabel.Text = 'Edge Detection Method';
            app.EdgeDetectionMethodDropDown = uidropdown(app.GridLayout2);
            app.EdgeDetectionMethodDropDown.Items = {'Canny', 'approxcanny', 'Sobel', 'log'};
            app.EdgeDetectionMethodDropDown.Value = 'Canny';
            app.EdgeDetectionThresholdEditField_2Label = uilabel(app.GridLayout2);
            app.EdgeDetectionThresholdEditField_2Label.Text = 'Edge Detection Threshold';
            app.EdgeDetectionThresholdEditField = uieditfield(app.GridLayout2, 'text');
            app.BasicInformationLabel = uilabel(app.GridLayout2);
            app.BasicInformationLabel.Text = 'Basic Information';
            app.DeleteSelectedStimuliButton = uibutton(app.GridLayout, 'push');
            app.DeleteSelectedStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedStimuliButtonPushed, true);
            app.DeleteSelectedStimuliButton.Text = 'Delete Selected Stimuli';
            app.PreviewEdgesCheckBox = uicheckbox(app.GridLayout);
            app.PreviewEdgesCheckBox.Text = 'Preview Edges';
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analysis(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end