classdef conversion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        StimuliWidthPixelsEditField   matlab.ui.control.NumericEditField
        StimuliWidthPixelsEditFieldLabel  matlab.ui.control.Label
        StimuliHeightPixelsEditField  matlab.ui.control.NumericEditField
        StimuliHeightPixelsEditFieldLabel  matlab.ui.control.Label
        CalculatedBasedontheImageUpdateRatenumberofimagesLabel  matlab.ui.control.Label
        DeleteSelectedButton          matlab.ui.control.Button
        AddImagesButton               matlab.ui.control.Button
        HelpButton                    matlab.ui.control.Button
        MeanLuminanceEditField        matlab.ui.control.NumericEditField
        MeanLuminanceEditFieldLabel   matlab.ui.control.Label
        RMSContrastEditField          matlab.ui.control.NumericEditField
        RMSContrastEditFieldLabel     matlab.ui.control.Label
        ImageListLabel                matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        StimuliDurationEditField      matlab.ui.control.NumericEditField
        StimuliDurationLabel          matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        ImageUpdateRateHzEditField    matlab.ui.control.NumericEditField
        ImageUpdateRateHzEditFieldLabel  matlab.ui.control.Label
        ImageConversionLabel          matlab.ui.control.Label
    end


    properties (Access = private)
        main_app
        preview_app
        information_app

        images

        current_image_no

        progress
    end

    properties (Access = public)
        stimuli
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.main_app = main_app;
            app.stimuli.label = "cfs_crafter_mask";
            app.current_image_no = 0;
            app.UITable.ColumnName = {'No', 'File Name', 'Selection'};
        end

        % Button pushed function: AddImagesButton
        function AddImagesButtonPushed(app, event)


            [file,path] = uigetfile({'*.jpg;*.jpeg;*.png'},...
                'Select Images', ...
                'MultiSelect', 'on');

            if isequal(file,0)
                return
            elseif isequal(class(file),'char')
                app.current_image_no = app.current_image_no +1;

                app.images(app.current_image_no).filename = convertCharsToStrings(fullfile(path,file));
                app.images(app.current_image_no).image_array = im2double(imread(app.images(app.current_image_no).filename));

            else
                for i = 1:length(file)
                    app.current_image_no = app.current_image_no +1;
                    app.images(app.current_image_no).filename = convertCharsToStrings(fullfile(path,file{i}));
                    app.images(app.current_image_no).image_array = im2double(imread(app.images(app.current_image_no).filename));


                end
            end


            no =   (1:length(app.images))';
            filename = [app.images(:).filename]';
            selection = false(length(app.images),1);
            app.UITable.Data = table(no,filename,selection,'VariableNames',["No","File Name","Selection"]);
            app.UITable.ColumnEditable = [false false true];

            app.StimuliDurationEditField.Value =  length(app.images)/app.ImageUpdateRateHzEditField.Value;

        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)

            if isempty(app.images)
                uialert(app.UIFigure,...
                    "No image!",...
                    "File Error",...
                    "Icon","error");

                return
            end

            if app.ImageUpdateRateHzEditField.Value > app.main_app.screen_info.refresh_rate

                uialert(app.UIFigure,...
                    "Image update rate is larger than screen refreshing rate!",...
                    "Input Error",...
                    "Icon","error");

                return
            end


            %warning the user when high rms contrast entered
            return_flag =  app.main_app.confirm_rms_alert(app,app.RMSContrastEditField.Value);

            if return_flag
                return
            end

            app.progress = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Creating Mask Components...');

            app.stimuli.stimuli_property.update_rate = app.ImageUpdateRateHzEditField.Value;
            app.stimuli.stimuli_property.rms_contrast = app.RMSContrastEditField.Value;
            app.stimuli.stimuli_property.mean_luminance = app.MeanLuminanceEditField.Value;


            nImages = length(app.images);


            image_stay_frame = round(app.main_app.screen_info.refresh_rate/ app.stimuli.stimuli_property.update_rate); %how many frames does the image stay on

            app.stimuli.stimuli_property.total_frames = image_stay_frame * nImages;

            app.stimuli.stimuli_property.sequence_duration = app.stimuli.stimuli_property.total_frames/app.main_app.screen_info.refresh_rate;

            app.StimuliDurationEditField.Value = app.stimuli.stimuli_property.sequence_duration;

            app.progress.Value = .1;
            app.progress.Message = 'Resizing Images...';

            stimuli_size = [app.StimuliWidthPixelsEditField.Value,app.StimuliHeightPixelsEditField.Value];

            for i = 1:nImages
                app.images(i).image_array = imresize(app.images(i).image_array,stimuli_size);
            end

            app.progress.Value = .25;
            app.progress.Message = 'Creating Stimuli from Images...';

            app.stimuli.individual_masks = cat(4,app.images(:).image_array);

            app.progress.Message = 'Setting Mean Luminance and RMS contrast...';
            app.progress.Value = .4;

            app.stimuli.individual_masks = app.main_app.set_RMS_and_luminance(app.stimuli.individual_masks,app.stimuli.stimuli_property.rms_contrast,app.stimuli.stimuli_property.mean_luminance);
            
            app.progress.Message = 'Constructing Stimuli Array from individual masks...';
            app.progress.Value = .5;

            app.stimuli.stimuli_array = repelem(app.stimuli.individual_masks,1,1,1,image_stay_frame);
            
            app.progress.Message = "Updating stimuli information..."
            app.progress.Value = .6;

            [app.stimuli.stimuli_property.stimuli_height_pixel, app.stimuli.stimuli_property.stimuli_width_pixel,~,~] = size(app.stimuli.stimuli_array);

            app.stimuli.screen_info = app.main_app.screen_info;

%             app.progress.Message = 'Updating stimuli information...';
            app.progress.Value = .7;

            [app.stimuli.stimuli_property,~] = app.main_app.convert_mask_info(app.stimuli.screen_info,app.stimuli.stimuli_property,app.stimuli.stimuli_array,app.stimuli.label);

            app.progress.Message = 'Finishing...';
            app.progress.Value = .9;

            app.preview_app = preview(app.main_app,app,app.stimuli);

            app.progress.Value = 1;

            close(app.progress)
        end

        % Button pushed function: DeleteSelectedButton
        function DeleteSelectedButtonPushed(app, event)
            row_index = app.UITable.Data.Selection;

            app.images(row_index)= [];


            no = (1:length(app.images))';
            filename = [app.images(:).filename]';
            selection = false(length(app.images),1);
            app.UITable.Data = table(no,filename,selection,'VariableNames',["No","File Name","Selection"]);

            app.current_image_no = length(app.images);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.main_app.enable_screen_info_edit
                delete(app.preview_app);
                delete(app.information_app);
            catch

            end

            delete(app)

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.information_app = information("Conversion.html");
        end

        % Value changed function: ImageUpdateRateHzEditField
        function ImageUpdateRateHzEditFieldValueChanged(app, event)
            app.StimuliDurationEditField.Value =  length(app.images)/app.ImageUpdateRateHzEditField.Value;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ImageConversionLabel = uilabel(app.GridLayout);
            app.ImageConversionLabel.Text = 'Image Conversion';
            app.ImageUpdateRateHzEditFieldLabel = uilabel(app.GridLayout);
            app.ImageUpdateRateHzEditFieldLabel.Text = 'Image Update Rate (Hz)';
            app.ImageUpdateRateHzEditField = uieditfield(app.GridLayout, 'numeric');
            app.ImageUpdateRateHzEditField.ValueChangedFcn = createCallbackFcn(app, @ImageUpdateRateHzEditFieldValueChanged, true);
            app.ImageUpdateRateHzEditField.Value = 10;
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StimuliDurationLabel = uilabel(app.GridLayout);
            app.StimuliDurationLabel.Text = '*Stimuli Duration';
            app.StimuliDurationEditField = uieditfield(app.GridLayout, 'numeric');
            app.StimuliDurationEditField.Editable = 'off';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'No'; 'File Name'};
            app.UITable.RowName = {};
            app.ImageListLabel = uilabel(app.GridLayout);
            app.ImageListLabel.Text = 'Image List';
            app.RMSContrastEditFieldLabel = uilabel(app.GridLayout);
            app.RMSContrastEditFieldLabel.Text = 'RMS Contrast';
            app.RMSContrastEditField = uieditfield(app.GridLayout, 'numeric');
            app.RMSContrastEditField.Limits = [0 1];
            app.RMSContrastEditField.Value = 0.15;
            app.MeanLuminanceEditFieldLabel = uilabel(app.GridLayout);
            app.MeanLuminanceEditFieldLabel.Text = 'Mean Luminance';
            app.MeanLuminanceEditField = uieditfield(app.GridLayout, 'numeric');
            app.MeanLuminanceEditField.Limits = [0 1];
            app.MeanLuminanceEditField.Value = 0.5;
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.AddImagesButton = uibutton(app.GridLayout, 'push');
            app.AddImagesButton.ButtonPushedFcn = createCallbackFcn(app, @AddImagesButtonPushed, true);
            app.AddImagesButton.Text = 'Add Images';
            app.DeleteSelectedButton = uibutton(app.GridLayout, 'push');
            app.DeleteSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteSelectedButtonPushed, true);
            app.DeleteSelectedButton.Text = 'Delete Selected';
            app.CalculatedBasedontheImageUpdateRatenumberofimagesLabel = uilabel(app.GridLayout);
            app.CalculatedBasedontheImageUpdateRatenumberofimagesLabel.Text = '*Calculated Based on the Image Update Rate & number of images';
            app.StimuliHeightPixelsEditFieldLabel = uilabel(app.GridLayout);
            app.StimuliHeightPixelsEditFieldLabel.Text = 'Stimuli Height (Pixels)';
            app.StimuliHeightPixelsEditField = uieditfield(app.GridLayout, 'numeric');
            app.StimuliHeightPixelsEditField.Limits = [1 Inf];
            app.StimuliHeightPixelsEditField.RoundFractionalValues = 'on';
            app.StimuliHeightPixelsEditField.Value = 200;
            app.StimuliWidthPixelsEditFieldLabel = uilabel(app.GridLayout);
            app.StimuliWidthPixelsEditFieldLabel.Text = 'Stimuli Width (Pixels)';
            app.StimuliWidthPixelsEditField = uieditfield(app.GridLayout, 'numeric');
            app.StimuliWidthPixelsEditField.Limits = [1 Inf];
            app.StimuliWidthPixelsEditField.RoundFractionalValues = 'on';
            app.StimuliWidthPixelsEditField.Value = 200;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = conversion(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end