classdef creation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        HelpButton                      matlab.ui.control.Button
        ItemTypeButtonGroup             matlab.ui.container.ButtonGroup
        ObjectButton                    matlab.ui.control.RadioButton
        FaceButton                      matlab.ui.control.RadioButton
        PatternShapeButtonGroup         matlab.ui.container.ButtonGroup
        DiamondButton                   matlab.ui.control.RadioButton
        SquareButton                    matlab.ui.control.RadioButton
        CircleButton                    matlab.ui.control.RadioButton
        StartTracingButton              matlab.ui.control.Button
        PatternNoiseTypeButtonGroup     matlab.ui.container.ButtonGroup
        PinkNoiseButton_2               matlab.ui.control.RadioButton
        WhiteNoiseButton_2              matlab.ui.control.RadioButton
        PatternColorButtonGroup         matlab.ui.container.ButtonGroup
        ColoredButton                   matlab.ui.control.RadioButton
        GrayscaleButton                 matlab.ui.control.RadioButton
        PatternFillButtonGroup          matlab.ui.container.ButtonGroup
        NoiseButton                     matlab.ui.control.RadioButton
        SolidButton                     matlab.ui.control.RadioButton
        MaskNoiseTypeButtonGroup        matlab.ui.container.ButtonGroup
        PinkNoiseButton                 matlab.ui.control.RadioButton
        WhiteNoiseButton                matlab.ui.control.RadioButton
        PatternTypeButtonGroup          matlab.ui.container.ButtonGroup
        TracedItemButton                matlab.ui.control.RadioButton
        GeometricShapeButton            matlab.ui.control.RadioButton
        MaskTypeButtonGroup             matlab.ui.container.ButtonGroup
        NoiseMaskButton                 matlab.ui.control.RadioButton
        PatternedElementButton          matlab.ui.control.RadioButton
        MaskSettingPanel                matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        MasksequencedurationsEditFieldLabel  matlab.ui.control.Label
        MasksequencedurationsEditField  matlab.ui.control.NumericEditField
        MeanLuminanceEditField          matlab.ui.control.NumericEditField
        MeanLuminanceEditFieldLabel     matlab.ui.control.Label
        RMSContrastEditField            matlab.ui.control.NumericEditField
        RMSContrastEditFieldLabel       matlab.ui.control.Label
        MaskupdaterateHzEditField       matlab.ui.control.NumericEditField
        MaskupdaterateHzEditFieldLabel  matlab.ui.control.Label
        HeightpixelEditField            matlab.ui.control.NumericEditField
        HeightpixelEditFieldLabel       matlab.ui.control.Label
        WidthpixelEditFieldLabel        matlab.ui.control.Label
        WidthpixelEditField             matlab.ui.control.NumericEditField
        StartButton                     matlab.ui.control.Button
        MaskCreationLabel               matlab.ui.control.Label
    end


    properties (Access = public)
        traced_object
        stimuli
        stimuli_setting
    end

    properties (Access = private)
        %Other component apps
        trace_app
        preview_app
        main_app
        information_app

        label
        stimuli_property
        stimuli_array
        progress
    end

    methods (Access = private)

        function stimuli_setting = read_stimuli_setting(app)

            stimuli_setting.mask_type = app.MaskTypeButtonGroup.SelectedObject.Text;
            stimuli_setting.mask_pattern_type = app.PatternTypeButtonGroup.SelectedObject.Text;
            stimuli_setting.mask_noise_type = app.MaskNoiseTypeButtonGroup.SelectedObject.Text;
            stimuli_setting.pattern_fill = app.PatternFillButtonGroup.SelectedObject.Text;
            stimuli_setting.pattern_color = app.PatternColorButtonGroup.SelectedObject.Text;
            stimuli_setting.pattern_noise_type = app.PatternNoiseTypeButtonGroup.SelectedObject.Text;
            stimuli_setting.pattern_shape = app.PatternShapeButtonGroup.SelectedObject.Text;
            stimuli_setting.traced_item_type = app.ItemTypeButtonGroup.SelectedObject.Text;
        end

        function stimuli_property = read_stimuli_property(app)


            stimuli_property.stimuli_width_pixel = app.WidthpixelEditField.Value;
            stimuli_property.stimuli_height_pixel = app.HeightpixelEditField.Value;
            stimuli_property.sequence_duration = app.MasksequencedurationsEditField.Value;
            stimuli_property.update_rate = app.MaskupdaterateHzEditField.Value;
            stimuli_property.rms_contrast = app.RMSContrastEditField.Value;
            stimuli_property.mean_luminance = app.MeanLuminanceEditField.Value;


        end

        function pink_noise = create_pink_noise(app,origin_patch_size)

            white_noise  = rand(origin_patch_size+1,origin_patch_size+1);

            [row,col] = size(white_noise);

            [XX,YY] = meshgrid(-col/2:col/2-1,row/2:-1:-(row/2-1));

            D = sqrt((XX).^2 + (YY).^2);
            D(end/2+1,end/2+1,:) = 1; % avoid log zero

            fft_image = fftshift(fft2(white_noise));

            fft_image = fftshift(fft_image.*(1./D));

            pink_noise = real(ifft2(fft_image));

            image_mean = sum(sum(pink_noise,1),2)./(col*row);
            pink_noise = (pink_noise-image_mean)./ ( max(max(pink_noise))- min(min(pink_noise)) )+ 0.5;
            pink_noise = pink_noise(1:end-1,1:end-1);
        end

        function [patch,paste_index] = patch_creation(app,stimuli_setting,stimuli_property,traced_object)

            row = stimuli_property.stimuli_height_pixel;
            col = stimuli_property.stimuli_width_pixel;

            ratio = .08:.02:.18; %image/patch

            patch_radius =  round(max([row col]).*(ratio)); % inner radius for circular patch

            half_patch_side = round(sqrt(pi)/2 * patch_radius); %half side length for square

            patch_radius_diamond = round(sqrt(pi/2) * patch_radius); % inner radius for diamond patch

            patch_size_lvl = length(ratio);

            count = 1;

            origin_patch_half_size = max(patch_radius_diamond) + 3;
            origin_patch_size = 2*origin_patch_half_size + 1;


            color_layer = 1;


            switch stimuli_setting.mask_pattern_type
                case "Geometric Shape"
                    switch stimuli_setting.pattern_fill
                        case "Solid"
                            switch stimuli_setting.pattern_color
                                case "Grayscale"
                                    patch_color = (0:.2:1)';

                                    for i = 1:numel(patch_color)
                                        origin_patch{i} = patch_color(i) .* ones(origin_patch_size);
                                    end

                                case "Colored"
                                    patch_color = [0 0 0;...
                                        1 0 0;...
                                        0 1 0;...
                                        0 0 1;...
                                        1 1 0];

                                    for i = 1:height(patch_color)
                                        origin_patch{i} = reshape(patch_color(i,:),1,1,3) .* ones(origin_patch_size,origin_patch_size,3);

                                    end

                                    color_layer = 3;
                            end

                        case "Noise"
                            for i = 1:12
                                origin_patch{i}  = (rand(origin_patch_size,origin_patch_size)-.5) + (mod(i,4))/5; %gray level before standardizing
                            end

                            if stimuli_setting.pattern_noise_type ==  "Pink Noise"
                                for i = 1:12

                                    %                                     origin_patch{i} = (create_pink_noise(app,origin_patch_size)-.5) + (mod(i,4))/5;

                                    origin_patch{i} = app.main_app.set_RMS_and_luminance(create_pink_noise(app,origin_patch_size),.15,(mod(i,4))/5);
                                end

                            end
                    end


                    [xx, yy] = meshgrid(-origin_patch_half_size:origin_patch_half_size,-origin_patch_half_size:origin_patch_half_size);

                    switch stimuli_setting.pattern_shape
                        case "Circle"
                            for i = 1:patch_size_lvl
                                shape_mask{i} = xx.^2 + yy.^2 < patch_radius(i)^2;
                                larger_mask = xx.^2 + yy.^2 < (patch_radius(i)+2)^2;
                                edge{i} = larger_mask - shape_mask{i};
                            end
                        case "Square"
                            for i = 1:patch_size_lvl
                                shape_mask{i} = abs(xx) < half_patch_side(i) & abs(yy) < half_patch_side(i);
                                larger_mask = abs(xx) < (half_patch_side(i)+2) & abs(yy) < (half_patch_side(i)+2);
                                edge{i} = larger_mask - shape_mask{i};
                            end
                        case "Diamond"
                            for i = 1:patch_size_lvl
                                diamond = strel('diamond',patch_radius_diamond(i));
                                pad_size = origin_patch_half_size-patch_radius_diamond(i);
                                shape_mask{i} = padarray(diamond.Neighborhood,[pad_size pad_size]);

                                diamond = strel('diamond',patch_radius_diamond(i)+2);
                                pad_size = origin_patch_half_size-(patch_radius_diamond(i)+2);
                                larger_mask = padarray(diamond.Neighborhood,[pad_size pad_size]);
                                edge{i} = larger_mask - shape_mask{i};
                            end
                    end


                    patch = cell(length(origin_patch)*length(shape_mask),1);
                    paste_index = cell(size(patch));
                    switch stimuli_setting.pattern_fill
                        case "Solid"
                            %Combine patch shape with patch fill
                            for i = 1:length(origin_patch)
                                for j = 1:length(shape_mask)
                                    patch{count} = origin_patch{i} .* shape_mask{j};

                                    paste_index{count} =  logical(repmat(shape_mask{j},1,1,color_layer));

                                    count = count+1;
                                end
                            end

                        case "Noise"
                            %Combine patch shape with patch fill
                            for i = 1:length(origin_patch)
                                for j = 1:length(shape_mask)
                                    patch{count} = origin_patch{i} .* shape_mask{j};
                                    paste_index{count} =  logical(shape_mask{j} + edge{j});

                                    count = count+1;
                                end
                            end
                    end


                case "Traced Item"

                    switch  stimuli_setting.traced_item_type
                        case "Face"
                            patch_ori = 0;
                        case "Object"
                            patch_ori = 0:45:315;

                    end
                    patch_ori_lvl = length(patch_ori);
                    patch_gray_value = .2:.2:.8;
                    patch_gray_lvl = length(patch_gray_value);
                    patch = cell(length(traced_object.object)*patch_size_lvl*patch_ori_lvl*patch_gray_lvl,1);
                    paste_index = cell(size(patch));

                    for i = 1:length(traced_object.object)
                        [object_row,object_col,color_layer] = size(traced_object.object{i});
                        %initialize the patch cell


                        for j = 1: patch_size_lvl

                            %                             resize_factor = patch_size(j)/max(object_row,object_col);
                            resize_factor = patch_radius(j)*2/max(object_row,object_col);

                            resized_object = imresize(traced_object.object{i},resize_factor);
                            resized_paste_index = imresize(traced_object.paste_index{i},resize_factor);

                            for k = 1:patch_ori_lvl
                                for l = 1:patch_gray_lvl
                                    patch{count} = imrotate(resized_object,patch_ori(k)).*patch_gray_value(l);
                                    paste_index{count} =  logical(repmat(imrotate(resized_paste_index,patch_ori(k)),1,1,color_layer));
                                    count = count + 1;
                                end
                            end
                        end
                    end
            end
        end


        function noise_mask_signle = create_single_noise_mask(app,stimuli_setting,stimuli_property)
            row = stimuli_property.stimuli_height_pixel;
            col = stimuli_property.stimuli_width_pixel;
            noise_mask_signle = rand(row,col);

            if stimuli_setting.mask_noise_type == "Pink Noise"
                [XX,YY] = meshgrid(-col/2:col/2-1,row/2:-1:-(row/2-1));

                D = sqrt((XX).^2 + (YY).^2);
                D(D < 1) = 1;
                D = fftshift(D);

                fft_image = fft2(noise_mask_signle).*(1./D);

                noise_mask_signle = real(ifft2(fft_image));

            end
        end

        function patterned_mask_single = create_single_patterned_mask(app,stimuli_property,patch,paste_index)


            row = stimuli_property.stimuli_height_pixel;
            col = stimuli_property.stimuli_width_pixel;

            [patch_xdim,patch_ydim,color_layer] = cellfun(@size,patch);
            max_patch_size = max(max([patch_xdim,patch_ydim]));
            color_layer = color_layer(1);

            ratio = 3:7; %image/patch


            total_patch_no = (max(ratio)^2)*20;

            patch_location = [randi(round(row + 3 * max_patch_size),total_patch_no,1) ...
                randi(round(col + 3 *max_patch_size),total_patch_no,1)];

            uncut_row = 5 * max_patch_size + row;
            uncut_col = 5 * max_patch_size + col;

            patterned_mask_single = zeros(uncut_row, uncut_col,color_layer);

            for i = 1:total_patch_no

                new_paste_index = false(size(patterned_mask_single));

                rand_patch_index = randi(length(patch));

                new_patch = patch{rand_patch_index};

                patch_index = paste_index{rand_patch_index};

                [sz1,sz2,~] = size(new_patch);

                new_paste_index(patch_location(i,1) : patch_location(i,1) + sz1 - 1, ...
                    patch_location(i,2) : patch_location(i,2) + sz2 - 1,:) =  paste_index{rand_patch_index};

                patterned_mask_single(new_paste_index) = new_patch(patch_index);

            end


            patterned_mask_single = patterned_mask_single(max_patch_size : row + max_patch_size - 1, max_patch_size: col + max_patch_size - 1,:);
        end


    end

    methods (Access = public)

        function  update_interface(app,stimuli_setting)
            switch stimuli_setting.mask_type
                case "Patterned Element"
                    app.PatternTypeButtonGroup.Enable = "on";
                    app.MaskNoiseTypeButtonGroup.Enable = "off";
                    switch stimuli_setting.mask_pattern_type
                        case "Geometric Shape"
                            app.PatternShapeButtonGroup.Enable = "on";
                            app.PatternFillButtonGroup.Enable = "on";
                            app.ItemTypeButtonGroup.Enable = "off";
                            app.StartTracingButton.Enable = "off";
                            app.StartButton.Enable = "on";
                            switch stimuli_setting.pattern_fill
                                case "Solid"
                                    app.PatternColorButtonGroup.Enable = "on";
                                    app.PatternNoiseTypeButtonGroup.Enable = "off";
                                case "Noise"
                                    app.PatternColorButtonGroup.Enable = "off";
                                    app.PatternNoiseTypeButtonGroup.Enable = "on";
                            end
                        case "Traced Item"
                            app.PatternShapeButtonGroup.Enable = "off";
                            app.PatternFillButtonGroup.Enable = "off";
                            app.PatternColorButtonGroup.Enable = "off";
                            app.PatternNoiseTypeButtonGroup.Enable = "off";
                            app.ItemTypeButtonGroup.Enable = "on";
                            app.StartTracingButton.Enable = "on";
                    end


                case "Noise Mask"
                    app.PatternTypeButtonGroup.Enable = "off";
                    app.PatternShapeButtonGroup.Enable = "off";
                    app.PatternFillButtonGroup.Enable = "off";
                    app.PatternColorButtonGroup.Enable = "off";
                    app.PatternNoiseTypeButtonGroup.Enable = "off";
                    app.ItemTypeButtonGroup.Enable = "off";
                    app.StartTracingButton.Enable = "off";
                    app.MaskNoiseTypeButtonGroup.Enable = "on";
                    app.StartButton.Enable = "on";
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.main_app = main_app;
            app.stimuli_setting = read_stimuli_setting(app);
            app.stimuli_property = read_stimuli_property(app);

            app.traced_object = struct('type',[],'object',[]);
            update_interface(app,app.stimuli_setting )

            app.label = "cfs_crafter_mask";
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.progress = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Checking Input...');


            app.stimuli_setting = read_stimuli_setting(app);
            app.stimuli_property = read_stimuli_property(app);

            if app.stimuli_setting.mask_type == "Patterned Element" && app.stimuli_setting.mask_pattern_type == "Traced Item"  && isempty(app.traced_object.object)
                uialert(app.UIFigure,...
                    "No traced item found! Trace item first before creating the mask, or choose a different mask type.",...
                    "Input Error",...
                    "Icon","error");
                close(app.progress);
                return
            end

            if app.stimuli_property.update_rate > app.main_app.screen_info.refresh_rate

                uialert(app.UIFigure,...
                    "Mask update rate is larger than screen refreshing rate!",...
                    "Input Error",...
                    "Icon","error");

                close(app.progress);
                return
            end

            %warning the user when high rms contrast entered
            return_flag =  app.main_app.confirm_rms_alert(app,app.RMSContrastEditField.Value);

            if return_flag
                close(app.progress);
                return
            end


            app.progress.Value = .1;
            app.progress.Message = 'Creating Mask Component...';

            if app.stimuli_setting.mask_type == "Patterned Element"
                [patch, paste_index] = patch_creation(app,app.stimuli_setting,app.stimuli_property,app.traced_object);
            else
                patch = [];
                paste_index = [];
            end

            app.progress.Message = 'Creating Individual Masks...';


            n_distinct_masks = app.stimuli_property.sequence_duration * app.stimuli_property.update_rate;
            mask_stay_frame = round(app.main_app.screen_info.refresh_rate/app.stimuli_property.update_rate);

            switch app.stimuli_setting.mask_type
                case "Patterned Element"
                    for i = 1:n_distinct_masks
                        individual_masks(:,:,:,i) = create_single_patterned_mask(app,app.stimuli_property,patch,paste_index);
                        app.progress.Value = .1 + .6*(i/n_distinct_masks);
                    end
                case "Noise Mask"

                    for i = 1:n_distinct_masks
                        individual_masks(:,:,:,i) = create_single_noise_mask(app,app.stimuli_setting,app.stimuli_property);
                        app.progress.Value = .1 + .6*(i/n_distinct_masks);
                    end

            end
            
           
            app.progress.Value = .8;
            app.progress.Message = 'Setting RMS Contrast & Luminance...';
            
            individual_masks = app.main_app.set_RMS_and_luminance(individual_masks,app.stimuli_property.rms_contrast,app.stimuli_property.mean_luminance);

             
            app.stimuli_array = repelem(individual_masks,1,1,1,mask_stay_frame);


%             app.stimuli_array = app.main_app.set_RMS_and_luminance(app.stimuli_array,app.stimuli_property.rms_contrast,app.stimuli_property.mean_luminance);

            app.progress.Value = .9;
            app.progress.Message = 'Update mask information...';


            [app.stimuli_property,~] = app.main_app.convert_mask_info(app.main_app.screen_info,app.stimuli_property,app.stimuli_array,app.label);


            app.stimuli = struct("label",app.label,"screen_info",app.main_app.screen_info,"individual_masks",individual_masks,"stimuli_property",app.stimuli_property,"stimuli_array",app.stimuli_array);


            app.progress.Value = 1;
            app.progress.Message = 'Finishing...';


            app.preview_app = preview(app.main_app,app,app.stimuli);


            close(app.progress)

        end

        % Button pushed function: StartTracingButton
        function StartTracingButtonPushed(app, event)
            app.trace_app = trace(app);
            app.stimuli_setting = read_stimuli_setting(app);
            update_interface(app,app.stimuli_setting)
        end

        % Selection changed function: ItemTypeButtonGroup, 
        % MaskNoiseTypeButtonGroup, MaskTypeButtonGroup, 
        % PatternColorButtonGroup, PatternFillButtonGroup, 
        % PatternNoiseTypeButtonGroup, PatternShapeButtonGroup, 
        % PatternTypeButtonGroup
        function StimuliSettingSelectionChanged(app, event)
            app.stimuli_setting = read_stimuli_setting(app);
            update_interface(app,app.stimuli_setting)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.main_app.enable_screen_info_edit
                delete(app.trace_app)
                delete(app.preview_app)
                delete(app.information_app)
            catch
            end

            delete(app)

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.information_app = information("Creation.html");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MaskCreationLabel = uilabel(app.GridLayout);
            app.MaskCreationLabel.Text = {'Mask Creation'; ''};
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MaskSettingPanel = uipanel(app.GridLayout);
            app.MaskSettingPanel.Title = 'Mask Setting';
            app.GridLayout2 = uigridlayout(app.MaskSettingPanel);
            app.WidthpixelEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WidthpixelEditField.Limits = [0 Inf];
            app.WidthpixelEditField.RoundFractionalValues = 'on';
            app.WidthpixelEditField.Value = 200;
            app.WidthpixelEditFieldLabel = uilabel(app.GridLayout2);
            app.WidthpixelEditFieldLabel.Text = 'Width (pixel)';
            app.HeightpixelEditFieldLabel = uilabel(app.GridLayout2);
            app.HeightpixelEditFieldLabel.Text = 'Height (pixel)';
            app.HeightpixelEditField = uieditfield(app.GridLayout2, 'numeric');
            app.HeightpixelEditField.Limits = [0 Inf];
            app.HeightpixelEditField.RoundFractionalValues = 'on';
            app.HeightpixelEditField.Value = 200;
            app.MaskupdaterateHzEditFieldLabel = uilabel(app.GridLayout2);
            app.MaskupdaterateHzEditFieldLabel.Text = 'Mask update rate (Hz)';
            app.MaskupdaterateHzEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaskupdaterateHzEditField.Limits = [0 Inf];
            app.MaskupdaterateHzEditField.Value = 10;
            app.RMSContrastEditFieldLabel = uilabel(app.GridLayout2);
            app.RMSContrastEditFieldLabel.Text = 'RMS Contrast';
            app.RMSContrastEditField = uieditfield(app.GridLayout2, 'numeric');
            app.RMSContrastEditField.Limits = [0 1];
            app.RMSContrastEditField.Value = 0.15;
            app.MeanLuminanceEditFieldLabel = uilabel(app.GridLayout2);
            app.MeanLuminanceEditFieldLabel.Text = 'Mean Luminance';
            app.MeanLuminanceEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MeanLuminanceEditField.Limits = [0 1];
            app.MeanLuminanceEditField.Value = 0.5;
            app.MasksequencedurationsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MasksequencedurationsEditField.Limits = [0 Inf];
            app.MasksequencedurationsEditField.Value = 2;
            app.MasksequencedurationsEditFieldLabel = uilabel(app.GridLayout2);
            app.MasksequencedurationsEditFieldLabel.Text = 'Mask sequence duration (s)';
            app.MaskTypeButtonGroup = uibuttongroup(app.GridLayout);
            app.MaskTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimuliSettingSelectionChanged, true);
            app.MaskTypeButtonGroup.Title = 'Mask Type';
            app.PatternedElementButton = uiradiobutton(app.MaskTypeButtonGroup);
            app.PatternedElementButton.Text = 'Patterned Element';
            app.PatternedElementButton.Value = true;
            app.NoiseMaskButton = uiradiobutton(app.MaskTypeButtonGroup);
            app.NoiseMaskButton.Text = 'Noise Mask';
            app.PatternTypeButtonGroup = uibuttongroup(app.GridLayout);
            app.PatternTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimuliSettingSelectionChanged, true);
            app.PatternTypeButtonGroup.Title = 'Pattern Type';
            app.GeometricShapeButton = uiradiobutton(app.PatternTypeButtonGroup);
            app.GeometricShapeButton.Text = 'Geometric Shape';
            app.GeometricShapeButton.Value = true;
            app.TracedItemButton = uiradiobutton(app.PatternTypeButtonGroup);
            app.TracedItemButton.Text = 'Traced Item';
            app.MaskNoiseTypeButtonGroup = uibuttongroup(app.GridLayout);
            app.MaskNoiseTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimuliSettingSelectionChanged, true);
            app.MaskNoiseTypeButtonGroup.Title = 'Mask Noise Type';
            app.WhiteNoiseButton = uiradiobutton(app.MaskNoiseTypeButtonGroup);
            app.WhiteNoiseButton.Text = 'White Noise';
            app.WhiteNoiseButton.Value = true;
            app.PinkNoiseButton = uiradiobutton(app.MaskNoiseTypeButtonGroup);
            app.PinkNoiseButton.Text = 'Pink Noise';
            app.PatternFillButtonGroup = uibuttongroup(app.GridLayout);
            app.PatternFillButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimuliSettingSelectionChanged, true);
            app.PatternFillButtonGroup.Title = 'Pattern Fill ';
            app.SolidButton = uiradiobutton(app.PatternFillButtonGroup);
            app.SolidButton.Text = 'Solid';
            app.SolidButton.Value = true;
            app.NoiseButton = uiradiobutton(app.PatternFillButtonGroup);
            app.NoiseButton.Text = 'Noise';
            app.PatternColorButtonGroup = uibuttongroup(app.GridLayout);
            app.PatternColorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimuliSettingSelectionChanged, true);
            app.PatternColorButtonGroup.Title = 'Pattern Color';
            app.GrayscaleButton = uiradiobutton(app.PatternColorButtonGroup);
            app.GrayscaleButton.Text = 'Grayscale';
            app.GrayscaleButton.Value = true;
            app.ColoredButton = uiradiobutton(app.PatternColorButtonGroup);
            app.ColoredButton.Text = 'Colored';
            app.PatternNoiseTypeButtonGroup = uibuttongroup(app.GridLayout);
            app.PatternNoiseTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimuliSettingSelectionChanged, true);
            app.PatternNoiseTypeButtonGroup.Title = 'Pattern Noise Type';
            app.WhiteNoiseButton_2 = uiradiobutton(app.PatternNoiseTypeButtonGroup);
            app.WhiteNoiseButton_2.Text = 'White Noise';
            app.WhiteNoiseButton_2.Value = true;
            app.PinkNoiseButton_2 = uiradiobutton(app.PatternNoiseTypeButtonGroup);
            app.PinkNoiseButton_2.Text = 'Pink Noise';
            app.StartTracingButton = uibutton(app.GridLayout, 'push');
            app.StartTracingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTracingButtonPushed, true);
            app.StartTracingButton.Text = 'Start Tracing';
            app.PatternShapeButtonGroup = uibuttongroup(app.GridLayout);
            app.PatternShapeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimuliSettingSelectionChanged, true);
            app.PatternShapeButtonGroup.Title = 'Pattern Shape';
            app.CircleButton = uiradiobutton(app.PatternShapeButtonGroup);
            app.CircleButton.Text = 'Circle';
            app.CircleButton.Value = true;
            app.SquareButton = uiradiobutton(app.PatternShapeButtonGroup);
            app.SquareButton.Text = 'Square';
            app.DiamondButton = uiradiobutton(app.PatternShapeButtonGroup);
            app.DiamondButton.Text = 'Diamond';
            app.ItemTypeButtonGroup = uibuttongroup(app.GridLayout);
            app.ItemTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StimuliSettingSelectionChanged, true);
            app.ItemTypeButtonGroup.Title = 'Item Type';
            app.FaceButton = uiradiobutton(app.ItemTypeButtonGroup);
            app.FaceButton.Text = 'Face';
            app.FaceButton.Value = true;
            app.ObjectButton = uiradiobutton(app.ItemTypeButtonGroup);
            app.ObjectButton.Text = 'Object';
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = creation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end