classdef display_analysis_results < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        HelpButton                matlab.ui.control.Button
        ValuePanel                matlab.ui.container.Panel
        GridLayout8               matlab.ui.container.GridLayout
        ValueUITable              matlab.ui.control.Table
        ValueUIAxes               matlab.ui.control.UIAxes
        SaturationPanel           matlab.ui.container.Panel
        GridLayout6               matlab.ui.container.GridLayout
        SaturationUITable         matlab.ui.control.Table
        SaturationUIAxes          matlab.ui.control.UIAxes
        HuePanel                  matlab.ui.container.Panel
        GridLayout5               matlab.ui.container.GridLayout
        HueUITable                matlab.ui.control.Table
        HueUIAxes                 matlab.ui.control.UIAxes
        SaveResultstoFIleButton   matlab.ui.control.Button
        BasicInformationPanel     matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        BasicInformationUITable   matlab.ui.control.Table
        FrequencyPanel            matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        SFUIAxes                  matlab.ui.control.UIAxes
        TFUIAxes                  matlab.ui.control.UIAxes
        MaskAnalysisResultsLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        calling_app % Description
        information_app

        color_map
        analysis_results
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, analysis_app, analysis_results)
            Alphabet = ["A" "B" "C" "D" "E" "F" "G" "H" "I" "J" "K" "L" "M" "N" "O" "P" "Q" "R" "S" "T" "U" "V" "W" "X" "Y" "Z"];
            app.analysis_results = analysis_results;
            saturation_map = hsv2rgb(reshape([ones(101,1) (0:.01:1)' ones(101,1)],101,1,3));
            saturation_map = squeeze(reshape(saturation_map,101,3,1));

            app.color_map{1} = [0 0.4470 0.7410];
            app.color_map{2} = [0.9290 0.6940 0.125];
            app.color_map{3} = [0.4940 0.1840 0.556];
            app.color_map{4} = [0.4660 0.6740 0.1880];
            app.color_map{5} = [0.3010 0.7450 0.9330];
            app.color_map{6} = [0.6350 0.0780 0.1840];

            app.calling_app = analysis_app;


            if app.calling_app.analysis_items.frequency == 0
                frequency = "0x";
            else
                frequency = "4x";
            end

            if app.calling_app.analysis_items.color == 0
                color = "0x";
            else
                color = "7x";
            end

            app.GridLayout.RowHeight = ["1x", "3x", frequency, color, "1x"];


            for i = 1:length(app.analysis_results)

                app.BasicInformationUITable.Data = [app.BasicInformationUITable.Data; struct2table(app.analysis_results(i).basic_info)];
                app.BasicInformationUITable.ColumnName = {'Symbol','Color','Total Frames', 'Entropy','Mean Luminance', 'RMS Contrast', 'Edge Density'};

                s = uistyle('BackgroundColor',app.color_map{mod(i-1,6)+1});
                addStyle(app.BasicInformationUITable,s,'cell',[i,1]);
            end

            if app.calling_app.analysis_items.frequency

                for i = 1:length(app.analysis_results)


                    loglog(app.analysis_results(i).frequency_results.spatial_frequency.frequency,app.analysis_results(i).frequency_results.spatial_frequency.psd,...
                        'Parent',app.SFUIAxes,'Color',app.color_map{mod(i-1,6)+1},"LineWidth",2);

                    hold(app.SFUIAxes,'on');

                    loglog(app.analysis_results(i).frequency_results.temporal_frequency.frequency,app.analysis_results(i).frequency_results.temporal_frequency.psd,...
                        'Parent',app.TFUIAxes,'Color',app.color_map{mod(i-1,6)+1},"LineWidth",2);

                    hold(app.TFUIAxes,'on');
                end

                disableDefaultInteractivity(app.SFUIAxes)
                disableDefaultInteractivity(app.TFUIAxes)


                app.SFUIAxes.XTickLabel = num2cell(app.SFUIAxes.XTick);
                app.TFUIAxes.XTickLabel = num2cell(app.TFUIAxes.XTick);


            end


            if app.calling_app.analysis_items.color

                plot_index = [];
                count = 1;

                for i = 1:length(app.analysis_results)

                    if  app.analysis_results(i).basic_info.color == "rgb"
                        plot_index = [plot_index,i];
                        for bin = 1:20
                            x = [numel(plot_index)-.3,  numel(plot_index)-.3, numel(plot_index)+.3, numel(plot_index)+.3];
                            hue_y = [app.analysis_results(i).hsv_results.hue.bin_edges(bin),...
                                app.analysis_results(i).hsv_results.hue.bin_edges(bin+1),...
                                app.analysis_results(i).hsv_results.hue.bin_edges(bin+1),...
                                app.analysis_results(i).hsv_results.hue.bin_edges(bin)];

                            saturation_y  = [app.analysis_results(i).hsv_results.saturation.bin_edges(bin),...
                                app.analysis_results(i).hsv_results.saturation.bin_edges(bin+1),...
                                app.analysis_results(i).hsv_results.saturation.bin_edges(bin+1),...
                                app.analysis_results(i).hsv_results.saturation.bin_edges(bin)];

                            value_y = [app.analysis_results(i).hsv_results.value.bin_edges(bin),...
                                app.analysis_results(i).hsv_results.value.bin_edges(bin+1),...
                                app.analysis_results(i).hsv_results.value.bin_edges(bin+1),...
                                app.analysis_results(i).hsv_results.value.bin_edges(bin)];

                            patch(x,hue_y,'k',"FaceAlpha",app.analysis_results(i).hsv_results.hue.bin_counts(bin),"EdgeAlpha",0,'parent',app.HueUIAxes);

                            patch(x,saturation_y,'k',"FaceAlpha",app.analysis_results(i).hsv_results.saturation.bin_counts(bin),"EdgeAlpha",0,'parent',app.SaturationUIAxes);

                            patch(x,value_y,'k',"FaceAlpha",app.analysis_results(i).hsv_results.value.bin_counts(bin),"EdgeAlpha",0,'parent',app.ValueUIAxes);
                        end


                        app.HueUITable.Data = [app.HueUITable.Data; ...
                            [table(app.analysis_results(i).basic_info.symbol,'VariableNames',"Symbol") struct2table(app.analysis_results(i).hsv_results.hue.summary)]];
                        app.SaturationUITable.Data = [app.SaturationUITable.Data;...
                            [table(app.analysis_results(i).basic_info.symbol,'VariableNames',"Symbol") struct2table(app.analysis_results(i).hsv_results.saturation.summary)]];
                        app.ValueUITable.Data = [app.ValueUITable.Data; ...
                            [table(app.analysis_results(i).basic_info.symbol,'VariableNames',"Symbol") struct2table(app.analysis_results(i).hsv_results.value.summary)]];

                        s = uistyle('BackgroundColor',app.color_map{mod(i-1,6)+1});
                        addStyle(app.HueUITable,s,'cell',[numel(plot_index),1]);
                        addStyle(app.SaturationUITable,s,'cell',[numel(plot_index),1]);
                        addStyle(app.ValueUITable,s,'cell',[numel(plot_index),1]);

                    end
                end

                app.HueUITable.ColumnName = {'Symbol','Mean','Median','Mode','Range'};
                app.SaturationUITable.ColumnName = {'Symbol','Mean','Median','Mode','Range'};
                app.ValueUITable.ColumnName = {'Symbol','Mean','Median','Mode','Range'};

                app.HueUIAxes.XTick = 0:numel(plot_index)+1;
                app.HueUIAxes.XTickLabel = ["", Alphabet(plot_index), ""];
                app.HueUIAxes.XLim = [0,numel(plot_index)+1];
                app.HueUIAxes.YLim = [0,1];


                app.SaturationUIAxes.XTick = 0:numel(plot_index)+1;
                app.SaturationUIAxes.XTickLabel = ["", Alphabet(plot_index), ""];
                app.SaturationUIAxes.XLim = [0,numel(plot_index)+1];
                app.SaturationUIAxes.YLim = [0,1];

                app.ValueUIAxes.XTick = 0:numel(plot_index)+1;
                app.ValueUIAxes.XTickLabel = ["", Alphabet(plot_index), ""];
                app.ValueUIAxes.XLim = [0,numel(plot_index)+1];
                app.ValueUIAxes.YLim = [0,1];

                colormap(app.HueUIAxes,'hsv');
                colormap(app.SaturationUIAxes,saturation_map);
                colormap(app.ValueUIAxes,'gray')

                colorbar(app.HueUIAxes,'Ticklabels',[],'Ticks',[])
                colorbar(app.SaturationUIAxes,'Ticklabels',[],'Ticks',[])
                colorbar(app.ValueUIAxes,'Ticklabels',[],'Ticks',[])

                disableDefaultInteractivity(app.HueUIAxes)
                disableDefaultInteractivity(app.SaturationUIAxes)
                disableDefaultInteractivity(app.ValueUIAxes)

            end


        end

        % Button pushed function: SaveResultstoFIleButton
        function SaveResultstoFIleButtonPushed(app, event)
            analysis_results = app.analysis_results;

            uisave('analysis_results','cfs_crafter_analysis_results.mat');

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.information_app = information("Analysis.html");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.information_app);
            delete(app)
        end

        % Callback function
        function SaveFiguresButtonPushed(app, event)

            path = uigetdir(pwd,"Select Folder to Save Figures");
            if isequal(path,0)
                return
            else
                cd(path);
                mkdir("CFS Crafter Analysis Figures")
                cd("CFS Crafter Analysis Figures")
                tempfigure = figure;
                copyobj(app.SFUIAxes,tempfigure)
                savefig(tempfigure,'Spatial Frequency');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.MaskAnalysisResultsLabel = uilabel(app.GridLayout);
            app.MaskAnalysisResultsLabel.Text = 'Mask Analysis Results';
            app.FrequencyPanel = uipanel(app.GridLayout);
            app.FrequencyPanel.Title = 'Frequency';
            app.GridLayout2 = uigridlayout(app.FrequencyPanel);
            app.TFUIAxes = uiaxes(app.GridLayout2);
            title(app.TFUIAxes, 'Temporal Power Spectral Density')
            xlabel(app.TFUIAxes, 'Hz')
            ylabel(app.TFUIAxes, 'PSD')
            zlabel(app.TFUIAxes, 'Z')
            app.SFUIAxes = uiaxes(app.GridLayout2);
            title(app.SFUIAxes, 'Spatial Power Spectral Density')
            xlabel(app.SFUIAxes, 'cpd')
            ylabel(app.SFUIAxes, 'PSD')
            zlabel(app.SFUIAxes, 'Z')
            app.BasicInformationPanel = uipanel(app.GridLayout);
            app.BasicInformationPanel.Title = 'Basic Information';
            app.GridLayout4 = uigridlayout(app.BasicInformationPanel);
            app.BasicInformationUITable = uitable(app.GridLayout4);
            app.BasicInformationUITable.ColumnName = '';
            app.BasicInformationUITable.RowName = {};
            app.SaveResultstoFIleButton = uibutton(app.GridLayout, 'push');
            app.SaveResultstoFIleButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultstoFIleButtonPushed, true);
            app.SaveResultstoFIleButton.Text = 'Save Results to FIle';
            app.HuePanel = uipanel(app.GridLayout);
            app.HuePanel.Title = 'Hue';
            app.GridLayout5 = uigridlayout(app.HuePanel);
            app.HueUIAxes = uiaxes(app.GridLayout5);
            xlabel(app.HueUIAxes, {'Stimuli'; ''})
            ylabel(app.HueUIAxes, {'Hue'; ''})
            app.HueUITable = uitable(app.GridLayout5);
            app.HueUITable.ColumnName = '';
            app.HueUITable.RowName = {};
            app.SaturationPanel = uipanel(app.GridLayout);
            app.SaturationPanel.Title = 'Saturation';
            app.GridLayout6 = uigridlayout(app.SaturationPanel);
            app.SaturationUIAxes = uiaxes(app.GridLayout6);
            xlabel(app.SaturationUIAxes, {'Stimuli'; ''})
            ylabel(app.SaturationUIAxes, 'Saturation')
            app.SaturationUITable = uitable(app.GridLayout6);
            app.SaturationUITable.ColumnName = '';
            app.SaturationUITable.RowName = {};
            app.ValuePanel = uipanel(app.GridLayout);
            app.ValuePanel.Title = 'Value';
            app.GridLayout8 = uigridlayout(app.ValuePanel);
            app.ValueUIAxes = uiaxes(app.GridLayout8);
            xlabel(app.ValueUIAxes, {'Stimuli'; ''})
            ylabel(app.ValueUIAxes, 'Value')
            app.ValueUITable = uitable(app.GridLayout8);
            app.ValueUITable.ColumnName = '';
            app.ValueUITable.RowName = {};
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = display_analysis_results(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end