classdef edge_preview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        HelpButton                    matlab.ui.control.Button
        SaveEdgestoFileButton         matlab.ui.control.Button
        StimuliLabel                  matlab.ui.control.Label
        PageNumberEditField           matlab.ui.control.EditField
        InformationPanel              matlab.ui.container.Panel
        InformationGridLayout         matlab.ui.container.GridLayout
        EdgeDetectionThresholdEditField  matlab.ui.control.EditField
        EdgeDetectionThresholdEditField_2Label  matlab.ui.control.Label
        EdgeDetectionMethodEditField  matlab.ui.control.EditField
        EdgeDetectionMethodEditFieldLabel  matlab.ui.control.Label
        EdgeDensityEditField          matlab.ui.control.NumericEditField
        EdgeDensityEditFieldLabel     matlab.ui.control.Label
        EdgePreviewLabel              matlab.ui.control.Label
        NextButton                    matlab.ui.control.Button
        PreviousButton                matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        main_app
        calling_app
        information_app

        bw_edges
        current_page
    end


    methods (Access = private)

        function check_current_page(app,current_page,total_page)
            if total_page == 1
                app.PreviousButton.Enable = 'off';
                app.NextButton.Enable = 'off';
            else
                if current_page == 1
                    app.PreviousButton.Enable = 'off';
                    app.NextButton.Enable = 'on';
                elseif current_page == total_page
                    app.PreviousButton.Enable = 'on';
                    app.NextButton.Enable = 'off';
                else
                    app.PreviousButton.Enable = 'on';
                    app.NextButton.Enable = 'on';
                end
            end
        end

        function update_edge_info(app,current_page)
            app.EdgeDetectionMethodEditField.Value = app.bw_edges(current_page).edge_detection_method;
            app.EdgeDetectionThresholdEditField.Value = strcat("[", join(string(app.bw_edges(current_page).edge_detection_threshold),','), "]");
            app.EdgeDensityEditField.Value = app.bw_edges(current_page).edge_density;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app, main_app, bw_edges, stimuli)
            app.main_app = main_app;
            app.calling_app = calling_app;
            app.bw_edges = bw_edges;


            imshow(app.bw_edges(1).edge_array(:,:,1),'parent',app.UIAxes);

            app.current_page = 1;

            update_edge_info(app,app.current_page);

            app.check_current_page(app.current_page,length(app.bw_edges))

            app.PageNumberEditField.Value = app.current_page + "/" + length(app.bw_edges);

            app.StimuliLabel.Text =  app.bw_edges(app.current_page).symbol + " - " + app.bw_edges(app.current_page).file;


        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            app.current_page =  app.current_page - 1;
            update_edge_info(app,app.current_page);
            imshow(app.bw_edges(app.current_page).edge_array(:,:,1),'parent',app.UIAxes);
            app.PageNumberEditField.Value = app.current_page + "/" + length(app.bw_edges);
            app.check_current_page(app.current_page,length(app.bw_edges))
            app.StimuliLabel.Text =  app.bw_edges(app.current_page).symbol + " - " + app.bw_edges(app.current_page).file;
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.current_page =  app.current_page + 1;
            update_edge_info(app,app.current_page);
            imshow(app.bw_edges(app.current_page).edge_array(:,:,1),'parent',app.UIAxes);
            app.PageNumberEditField.Value = app.current_page + "/" + length(app.bw_edges);
            app.check_current_page(app.current_page,length(app.bw_edges))
            app.StimuliLabel.Text =  app.bw_edges(app.current_page).symbol + " - " + app.bw_edges(app.current_page).file;
        end

        % Button pushed function: SaveEdgestoFileButton
        function SaveEdgestoFileButtonPushed(app, event)
            progress = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Saving File','Indeterminate','on');

            bw_edges = app.bw_edges;
            uisave('bw_edges','bw_edges.mat');

            close(progress)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.information_app);
            delete(app)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.information_app = information("Edge_preview.html");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PreviousButton = uibutton(app.GridLayout, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Text = 'Previous';
            app.NextButton = uibutton(app.GridLayout, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.EdgePreviewLabel = uilabel(app.GridLayout);
            app.EdgePreviewLabel.Text = 'Edge Preview';
            app.InformationPanel = uipanel(app.GridLayout);
            app.InformationPanel.Title = 'Information';
            app.InformationGridLayout = uigridlayout(app.InformationPanel);
            app.EdgeDensityEditFieldLabel = uilabel(app.InformationGridLayout);
            app.EdgeDensityEditFieldLabel.Text = 'Edge Density';
            app.EdgeDensityEditField = uieditfield(app.InformationGridLayout, 'numeric');
            app.EdgeDensityEditField.ValueDisplayFormat = '%.2f';
            app.EdgeDensityEditField.Editable = 'off';
            app.EdgeDetectionMethodEditFieldLabel = uilabel(app.InformationGridLayout);
            app.EdgeDetectionMethodEditFieldLabel.Text = 'Edge Detection Method';
            app.EdgeDetectionMethodEditField = uieditfield(app.InformationGridLayout, 'text');
            app.EdgeDetectionMethodEditField.Editable = 'off';
            app.EdgeDetectionMethodEditField.Value = 'Canny';
            app.EdgeDetectionThresholdEditField_2Label = uilabel(app.InformationGridLayout);
            app.EdgeDetectionThresholdEditField_2Label.Text = 'Edge Detection Threshold';
            app.EdgeDetectionThresholdEditField = uieditfield(app.InformationGridLayout, 'text');
            app.EdgeDetectionThresholdEditField.Editable = 'off';
            app.EdgeDetectionThresholdEditField.Value = '0';
            app.PageNumberEditField = uieditfield(app.GridLayout, 'text');
            app.StimuliLabel = uilabel(app.GridLayout);
            app.StimuliLabel.Text = '';
            app.SaveEdgestoFileButton = uibutton(app.GridLayout, 'push');
            app.SaveEdgestoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEdgestoFileButtonPushed, true);
            app.SaveEdgestoFileButton.Text = 'Save Edges to File';
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = edge_preview(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end