classdef modification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        HelpButton                     matlab.ui.control.Button
        MeanLuminanceEditField         matlab.ui.control.NumericEditField
        MeanLuminanceEditFieldLabel    matlab.ui.control.Label
        RMSContrastEditField           matlab.ui.control.NumericEditField
        RMSContrastLabel               matlab.ui.control.Label
        OrientationPanel               matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        SigmadegreeEditFieldLabel      matlab.ui.control.Label
        SigmadegreeEditField           matlab.ui.control.NumericEditField
        OrientationdegreeEditFieldLabel  matlab.ui.control.Label
        OrientationdegreeEditField     matlab.ui.control.NumericEditField
        TemporalPanel                  matlab.ui.container.Panel
        GridLayout2_3                  matlab.ui.container.GridLayout
        TF_FilterTypeDropDown          matlab.ui.control.DropDown
        FilterTypeDropDown_2Label      matlab.ui.control.Label
        TF_FilterMethodDropDown        matlab.ui.control.DropDown
        FilterMethodDropDown_2Label    matlab.ui.control.Label
        OrderEditField_3Label          matlab.ui.control.Label
        TF_OrderEditField              matlab.ui.control.NumericEditField
        TF_HighCutoffEditField         matlab.ui.control.NumericEditField
        CutoffcpsLabel                 matlab.ui.control.Label
        TF_LowCutoffEditField          matlab.ui.control.NumericEditField
        PhaseScrambleCheckBox          matlab.ui.control.CheckBox
        OrientationFilteringCheckBox   matlab.ui.control.CheckBox
        TemporalFilteringCheckBox      matlab.ui.control.CheckBox
        SpatialFilteringCheckBox       matlab.ui.control.CheckBox
        SpatialPanel                   matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        SF_HighCutoffEditField         matlab.ui.control.NumericEditField
        SF_LowCutoffEditField          matlab.ui.control.NumericEditField
        CutoffcpdLabel_4               matlab.ui.control.Label
        SF_FilterTypeDropDown          matlab.ui.control.DropDown
        FilterTypeDropDownLabel        matlab.ui.control.Label
        SF_FilterMethodDropDown        matlab.ui.control.DropDown
        FilterMethodDropDownLabel      matlab.ui.control.Label
        OrderEditFieldLabel            matlab.ui.control.Label
        SF_OrderEditField              matlab.ui.control.NumericEditField
        PhaseScramblePanel             matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        FrequencyRangeDropDown         matlab.ui.control.DropDown
        FrequencyRangeDropDownLabel    matlab.ui.control.Label
        ScramblingIndexEditFieldLabel  matlab.ui.control.Label
        ScramblingIndexEditField       matlab.ui.control.NumericEditField
        PS_HighCutoffEditField         matlab.ui.control.NumericEditField
        CutoffcpdLabel_3               matlab.ui.control.Label
        PS_LowCutoffEditField          matlab.ui.control.NumericEditField
        ChooseStimuliButton            matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
        ChooseMaskEditField            matlab.ui.control.EditField
        StimuliModificationLabel       matlab.ui.control.Label
    end


    properties (Access = public)
        stimuli % Description

    end

    properties (Access = private)
        modification_setting
        main_app
        preview_app
        information_app
    end


    methods (Access = private)

        function filter = low_pass_filter(~,type,dist_map,cutoff,varagin)

            switch type
                case 'Ideal'
                    filter = double(abs(dist_map) < cutoff);
                case 'Gaussian'
                    filter = exp(-dist_map.^2/(2*(cutoff^2)));
                case 'Butterworth'
                    order = varagin;
                    filter = 1./((1+ abs(dist_map)./cutoff).^(2*order));
                case 'Log Gaussian'
                    fPeak = cutoff;
                    sigma = .5; %bandwidth in octave = 1, sigma = 1/2, need to check
                    filter = exp(-((log2(abs(dist_map))-log2(fPeak)).^2)/(2*log2(sqrt(2).^sigma)^2));
                    filter(dist_map<fPeak) = 1;
            end

            filter = fftshift(filter);
        end

        function filter = high_pass_filter(~,type,dist_map,cutoff,varagin)

            switch type
                case 'Ideal'
                    filter = double(abs(dist_map) > cutoff);
                case 'Gaussian'
                    filter = 1 - exp(-abs(dist_map).^2/(2*(cutoff^2)));
                case 'Butterworth'
                    order = varagin;
                    filter = 1./((1+ cutoff./abs(dist_map)).^(2*order));
                case 'Log Gaussian'
                    fPeak = cutoff;
                    sigma = .5; %bandwidth in octave = 1, sigma = 1/2, need to check
                    filter = exp(-((log2(abs(dist_map))-log2(fPeak)).^2)/(2*log2(sqrt(2).^sigma)^2));
                    filter(dist_map>fPeak) = 1;

            end

            filter = fftshift(filter);
        end

        function filter = band_pass_filter(~,type,dist_map,cutoff_low,cutoff_high,varagin)

            switch type
                case 'Ideal'
                    filter = double(abs(dist_map) < cutoff_high & abs(dist_map) > cutoff_low);
                case 'Gaussian'
                    filter = exp(-dist_map.^2/(2*(cutoff_high^2))) - exp(-dist_map.^2/(2*(cutoff_low^2)));
                case 'Butterworth'
                    order = varagin;
                    filter = 1 - 1./(1 + (((cutoff_high - cutoff_low).* abs(dist_map))./(dist_map.^2 - cutoff_low.^2)).^(2*order));
                case 'Log Gaussian'
                    fPeak = (cutoff_high-cutoff_low)/2;
                    sigma = log2(cutoff_low/cutoff_high); %sigma in octave sigma = log2(f2/f1)
                    filter = exp(-((log2(abs(dist_map))-log2(fPeak)).^2)/(2*log2(sqrt(2).^sigma)^2));
            end
            filter = filter./max(max(filter));
            filter = fftshift(filter);
        end

        function filter = orientation_filter(~,theta,orientation,sigma)

            orientation = orientation * pi/180;
            sigma = sigma * pi/180;
            sinDiff = sin(theta) * cos(orientation) - cos(theta) * sin(orientation);    % amplitudes for diff(points in the image, selected orientation).

            cosDiff = cos(theta) * cos(orientation) + sin(theta) * sin(orientation);
            diffTheta = abs( atan2( sinDiff,cosDiff ) );  %  sine= o/h, and cosine = a/h.. thus ~the same ratio for o/a (tan, the diff angle)
            oriBand = exp((-diffTheta .^2) / (2*sigma^2));

            % repeat the process for the opposite angles (ie, +180 degrees), since the fft has a mirror image in the spectrum

            orientation = orientation+pi; % 180 deg offset
            sinDiff = sin(theta) * cos(orientation) - cos(theta) * sin(orientation);
            cosDiff = cos(theta) * cos(orientation) + sin(theta) * sin(orientation);
            diffTheta = abs( atan2( sinDiff,cosDiff ) );
            filter = oriBand + exp((-diffTheta .^2) / (2*sigma^2));
            filter = fftshift(filter);
        end

        function scrambled_fft = phase_scramble(~,masks_fft,dist_map,lower_cutoff,higher_cutoff,scrambling_index)

            range = dist_map >= lower_cutoff & dist_map <= higher_cutoff;

            range = fftshift(range);

            random_phase = scrambling_index .* range .* 2 * pi .* rand();

            magnitude = abs(masks_fft);
            phase = angle(masks_fft);
            phase = phase + random_phase;
            scrambled_fft = magnitude.*exp(1i*(phase));

        end

        function modification_setting = read_modification_setting(app)
            modification_setting.rms_contrast = app.RMSContrastEditField.Value;
            modification_setting.mean_luminance = app.MeanLuminanceEditField.Value;
            modification_setting.SF.status = app.SpatialFilteringCheckBox.Value;
            modification_setting.TF.status = app.TemporalFilteringCheckBox.Value;
            modification_setting.OF.status = app.OrientationFilteringCheckBox.Value;
            modification_setting.PS.status = app.PhaseScrambleCheckBox.Value;

            if modification_setting.SF.status
                modification_setting.SF.type = app.SF_FilterTypeDropDown.Value;
                modification_setting.SF.method = app.SF_FilterMethodDropDown.Value;
                switch modification_setting.SF.type
                    case 'Band-pass'
                        modification_setting.SF.cutoff_cpd = [app.SF_LowCutoffEditField.Value app.SF_HighCutoffEditField.Value];
                    otherwise
                        modification_setting.SF.cutoff_cpd = app.SF_LowCutoffEditField.Value;
                end


                switch modification_setting.SF.method
                    case 'Butterworth'
                        modification_setting.SF.order = app.SF_OrderEditField.Value;
                end

                app.FrequencyRangeDropDown.Enable = 'off';
                app.FrequencyRangeDropDown.Value = 'All';
                modification_setting.PS.frequency_range = app.FrequencyRangeDropDown.Value ;
            else
                app.FrequencyRangeDropDown.Enable = 'on';
            end

            if modification_setting.TF.status
                modification_setting.TF.type = app.TF_FilterTypeDropDown.Value;
                modification_setting.TF.method = app.TF_FilterMethodDropDown.Value;
                switch modification_setting.TF.type
                    case 'Band-pass'
                        modification_setting.TF.cutoff_hz = [app.TF_LowCutoffEditField.Value app.TF_HighCutoffEditField.Value];
                    otherwise
                        modification_setting.TF.cutoff_hz = app.TF_LowCutoffEditField.Value;
                end


                switch modification_setting.TF.method
                    case 'Butterworth'
                        modification_setting.TF.order = app.TF_OrderEditField.Value;

                end
            end

            if modification_setting.OF.status
                modification_setting.OF.orientation = app.OrientationdegreeEditField.Value;
                modification_setting.OF.sigma = app.SigmadegreeEditField.Value;
            end

            if modification_setting.PS.status
                modification_setting.PS.frequency_range = app.FrequencyRangeDropDown.Value;
                modification_setting.PS.scrambling_index = app.ScramblingIndexEditField.Value;

                switch modification_setting.PS.frequency_range
                    case 'Band-pass'
                        modification_setting.PS.cutoff_cpd = [app.PS_LowCutoffEditField.Value,app.PS_HighCutoffEditField.Value];
                    case {'Low-pass', 'High-pass'}
                        modification_setting.PS.cutoff_cpd = app.PS_LowCutoffEditField.Value;
                end
            end
        end

        function  update_interface(app,modification_setting)


            if modification_setting.SF.status
                app.SpatialPanel.Enable = 'on';
                switch modification_setting.SF.type
                    case 'Band-pass'
                        app.SF_HighCutoffEditField.Enable = 'on';
                    otherwise
                        app.SF_HighCutoffEditField.Enable = 'off';
                end

                switch modification_setting.SF.method
                    case 'Butterworth'
                        app.SF_OrderEditField.Enable = 'on';
                    otherwise
                        app.SF_OrderEditField.Enable = 'off';
                end
            else
                app.SpatialPanel.Enable = 'off';
            end

            if modification_setting.TF.status
                app.TemporalPanel.Enable = 'on';
                switch modification_setting.TF.type
                    case 'Band-pass'
                        app.TF_HighCutoffEditField.Enable = 'on';
                    otherwise
                        app.TF_HighCutoffEditField.Enable = 'off';
                end

                switch modification_setting.TF.method
                    case 'Butterworth'
                        app.TF_OrderEditField.Enable = 'on';
                    otherwise
                        app.TF_OrderEditField.Enable = 'off';
                end
            else
                app.TemporalPanel.Enable = 'off';
            end

            if modification_setting.OF.status
                app.OrientationPanel.Enable = 'on';
            else
                app.OrientationPanel.Enable = 'off';
            end

            if modification_setting.PS.status
                app.PhaseScramblePanel.Enable = 'on';
                switch modification_setting.PS.frequency_range
                    case 'All'
                        app.PS_HighCutoffEditField.Enable = 'off';
                        app.PS_LowCutoffEditField.Enable = 'off';
                    case 'Band-pass'
                        app.PS_HighCutoffEditField.Enable = 'on';
                        app.PS_LowCutoffEditField.Enable = 'on';
                    otherwise
                        app.PS_HighCutoffEditField.Enable = 'off';
                        app.PS_LowCutoffEditField.Enable = 'on';

                end
            else
                app.PhaseScramblePanel.Enable = 'off';
            end
        end

        function filter = create_filter(app,type,method,cutoff,map,varagin)
            switch type
                case 'Low-pass'
                    switch method
                        case 'Butterworth'
                            filter = app.low_pass_filter(method,map,cutoff,varagin);
                        otherwise
                            filter = app.low_pass_filter(method,map,cutoff);

                    end
                case 'High-pass'
                    switch method
                        case 'Butterworth'
                            filter = app.high_pass_filter(method,map,cutoff,varagin);
                        otherwise
                            filter = app.high_pass_filter(method,map,cutoff);

                    end
                case 'Band-pass'
                    switch method
                        case 'Butterworth'
                            filter = app.band_pass_filter(method,map,cutoff(1),cutoff(2),varagin);
                        otherwise
                            filter = app.band_pass_filter(method,map,cutoff(1),cutoff(2));
                    end

            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app)
            app.main_app = main_app;

            app.modification_setting = read_modification_setting(app);
            update_interface(app,app.modification_setting);

        end

        % Button pushed function: ChooseStimuliButton
        function ChooseStimuliButtonPushed(app, event)
            [file,path] = uigetfile('*.mat;*.jpeg;*.jpg;*.png');

            if file == 0
                return
            end

            app.ChooseMaskEditField.Value = fullfile(path,file);


        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~isfile(app.ChooseMaskEditField.Value) % isempty(app.ChooseMaskEditField.Value) ||

                uialert(app.UIFigure,...
                    "No File!",...
                    "File Error",...
                    "Icon","error");


                return
            end


            [~,~,extension] = fileparts(app.ChooseMaskEditField.Value);

            if extension == ".mat"

                temp = load(app.ChooseMaskEditField.Value);
                if isfield(temp,'stimuli')
                    app.stimuli = temp.stimuli;
                else

                    uialert(app.UIFigure,...
                        "The selected .mat file is not created by CFS-Crafter!",...
                        "File Error",...
                        "Icon","error");
                    return
                end

            else
                if app.TemporalFilteringCheckBox.Value

                    uialert(app.UIFigure,...
                        "Temporal Filtering cannot be applied to image!",...
                        "Selection Error",...
                        "Icon","error");


                    app.TemporalFilteringCheckBox.Value = 0;
                    app.modification_setting = read_modification_setting(app);
                    update_interface(app,app.modification_setting);

                    return
                end

                app.stimuli.stimuli_array = im2double(imread(app.ChooseMaskEditField.Value));

                app.stimuli.label = "cfs_crafter_image";
                app.stimuli.screen_info = app.main_app.screen_info;
                app.stimuli.stimuli_property = [];

                [app.stimuli.stimuli_property,bw_edges] = app.main_app.convert_mask_info(app.stimuli.screen_info,app.stimuli.stimuli_property,...
                    app.stimuli.stimuli_array, app.stimuli.label);

            end


            app.modification_setting = read_modification_setting(app);

            if app.SpatialFilteringCheckBox.Value
                if (any(app.modification_setting.SF.cutoff_cpd == 0) )...
                        || ((length(app.modification_setting.SF.cutoff_cpd) == 2 && app.modification_setting.SF.cutoff_cpd(1) >= app.modification_setting.SF.cutoff_cpd(2)))
                    uialert(app.UIFigure,...
                        "Invalid input to spatial cutoff frequency!",...
                        "Input Error",...
                        "Icon","error");
                    return
                end
            end


            if app.TemporalFilteringCheckBox.Value
                if (any(app.modification_setting.TF.cutoff_hz == 0) )...
                        || ((length(app.modification_setting.TF.cutoff_hz) == 2 && app.modification_setting.TF.cutoff_hz(1) >= app.modification_setting.TF.cutoff_hz(2)))
                    uialert(app.UIFigure,...
                        "Invalid input to temporal cutoff frequency!",...
                        "Input Error",...
                        "Icon","error");
                    return
                end
            end


            if app.PhaseScrambleCheckBox.Value && app.modification_setting.PS.frequency_range ~= "All"
                if (any(app.modification_setting.PS.cutoff_cpd == 0) )...
                        || ((length(app.modification_setting.PS.cutoff_cpd) == 2 && app.modification_setting.PS.cutoff_cpd(1) >= app.modification_setting.PS.cutoff_cpd(2)))
                    uialert(app.UIFigure,...
                        "Invalid input to phase scramble cutoff frequency!",...
                        "Input Error",...
                        "Icon","error");
                    return
                end
            end


            %warning the user when high rms contrast entered
            return_flag = app.main_app.confirm_rms_alert(app,app.RMSContrastEditField.Value);

            if return_flag
                return
            end


            progress = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Starting...');

            progress.Value = .1;
            progress.Message = 'Loading Mask...';

            [row,col,color,frames] = size(app.stimuli.stimuli_array);

            padded_stimuli_dim = [max(row,col),max(row,col),color,frames];

            progress.Value = .2;
            progress.Message = 'Setting Up Filtering Parameters...';

            [XX,YY] = meshgrid(-padded_stimuli_dim(1)/2:padded_stimuli_dim(1)/2-1,padded_stimuli_dim(1)/2:-1:-(padded_stimuli_dim(1)/2-1));

            spatial_map = sqrt((XX).^2 + (YY).^2); % distance map

            progress.Value = .3;
            progress.Message = 'Creating Temporal Filter...';

            if app.modification_setting.TF.status

                temporal_map = abs(-app.stimuli.stimuli_property.total_frames/2:app.stimuli.stimuli_property.total_frames/2-1);
                temporal_map = reshape(temporal_map,1,1,app.stimuli.stimuli_property.total_frames);

                TF_cutoff = app.modification_setting.TF.cutoff_hz * app.stimuli.stimuli_property.sequence_duration;

                if app.modification_setting.TF.method == "Butterworth"
                    temporal_filter = app.create_filter(app.modification_setting.TF.type,app.modification_setting.TF.method,TF_cutoff,temporal_map,app.modification_setting.TF.order);
                else
                    temporal_filter = app.create_filter(app.modification_setting.TF.type,app.modification_setting.TF.method,TF_cutoff,temporal_map);
                end

                temporal_filter = reshape(temporal_filter,1,1,1,[]);
            else
                temporal_filter = 1;
            end


            progress.Value = .5;
            progress.Message = 'Creating Spatial Filter..';

            if app.modification_setting.SF.status

                SF_cutoff = app.modification_setting.SF.cutoff_cpd *  app.main_app.pix2degree(padded_stimuli_dim(1),app.stimuli.screen_info.screen_width_cm,app.stimuli.screen_info.screen_width_pixel,app.stimuli.screen_info.viewing_distance);


                if app.modification_setting.SF.method == "Butterworth"
                    spatial_filter = app.create_filter(app.modification_setting.SF.type,app.modification_setting.SF.method,SF_cutoff,spatial_map,app.modification_setting.SF.order);
                else
                    spatial_filter = app.create_filter(app.modification_setting.SF.type,app.modification_setting.SF.method,SF_cutoff,spatial_map);
                end

            else
                spatial_filter = 1;

            end


            progress.Value = .6;
            progress.Message = 'Creating Orientation Filter..';

            if app.modification_setting.OF.status
                theta = atan2(-YY,XX); %orientation map
                ori_filter = app.orientation_filter(theta,app.modification_setting.OF.orientation,app.modification_setting.OF.sigma);
            else
                ori_filter = 1;
            end


            progress.Value = .7;
            progress.Message = 'Apply Filters..';

            fft_stimuli = fftn(app.stimuli.stimuli_array,padded_stimuli_dim);
            fft_stimuli = fft_stimuli .*  temporal_filter;
            fft_stimuli = fft_stimuli .*  spatial_filter;
            fft_stimuli = fft_stimuli .*  ori_filter;

            progress.Value = .8;
            progress.Message = 'Apply Phase Scramble..';

            if app.modification_setting.PS.status
                switch app.modification_setting.PS.frequency_range
                    case 'All'
                        PS_low = 0;
                        PS_high = ceil(max(max(spatial_map)));
                    case 'Low-pass'
                        PS_low = 0;
                        PS_high = app.modification_setting.PS.cutoff_cpd * app.main_app.pix2degree(padded_stimuli_dim(1),...
                            app.stimuli.screen_info.screen_width_cm,app.stimuli.screen_info.screen_width_pixel,app.stimuli.screen_info.viewing_distance);
                    case 'High-pass'
                        PS_low = app.modification_setting.PS.cutoff_cpd * app.main_app.pix2degree(padded_stimuli_dim(1),...
                            app.stimuli.screen_info.screen_width_cm,app.stimuli.screen_info.screen_width_pixel,app.stimuli.screen_info.viewing_distance);
                        PS_high = ceil(max(max(spatial_map)));
                    case 'Band-pass'
                        PS_low = app.modification_setting.PS.cutoff_cpd(1) * app.main_app.pix2degree(padded_stimuli_dim(1),app.stimuli.screen_info.screen_width_cm,app.stimuli.screen_info.screen_width_pixel,app.stimuli.screen_info.viewing_distance);
                        PS_high = app.modification_setting.PS.cutoff_cpd(2) * app.main_app.pix2degree(padded_stimuli_dim(1),app.stimuli.screen_info.screen_width_cm,app.stimuli.screen_info.screen_width_pixel,app.stimuli.screen_info.viewing_distance);
                end

                freq_range = spatial_map >= PS_low & spatial_map <= PS_high;

                freq_range = fftshift(freq_range);

                if  isequal(app.stimuli.label,"cfs_crafter_image")
                    mask_stay_frame = 1;

                    n_unique_phase = 1;
                else
                    mask_stay_frame = app.stimuli.screen_info.refresh_rate / app.stimuli.stimuli_property.update_rate;

                    n_unique_phase = padded_stimuli_dim(4)/mask_stay_frame;
                end


                random_phase = app.modification_setting.PS.scrambling_index .* freq_range .* angle(fftn(rand(padded_stimuli_dim(1),padded_stimuli_dim(2),1,n_unique_phase)));
                random_phase = repmat(random_phase,1,1,1,mask_stay_frame);

                magnitude = abs(fft_stimuli);
                phase = angle(fft_stimuli);
                phase = phase + random_phase;
                fft_stimuli = magnitude.*exp(1i*(phase));

            end

            progress.Value = .9;
            progress.Message = 'Changing Luminance & RMS Contrast..';

            modified_stimuli = real(ifftn(fft_stimuli));
            modified_stimuli = modified_stimuli(1:row,1:col,:,:);

            modified_stimuli= app.main_app.set_RMS_and_luminance(modified_stimuli,app.modification_setting.rms_contrast,app.modification_setting.mean_luminance);

            app.stimuli.stimuli_array = modified_stimuli;
            [app.stimuli.stimuli_property,~] = app.main_app.convert_mask_info(app.stimuli.screen_info,app.stimuli.stimuli_property,app.stimuli.stimuli_array,app.stimuli.label);

            if extension == ".mat"
                if ~app.modification_setting.TF.status
                    mask_stay_frame = round(app.stimuli.screen_info.refresh_rate/app.stimuli.stimuli_property.update_rate);
                    app.stimuli.individual_masks = app.stimuli.stimuli_array(:,:,:,1:mask_stay_frame:end);
                else
                    app.stimuli = rmfield(app.stimuli,'individual_masks');
                end
            else
                app.stimuli.individual_masks = app.stimuli.stimuli_array;
            end

            progress.Value = 1;
            progress.Message = 'Finishing..';

            app.preview_app = preview(app.main_app,app,app.stimuli);


            close(progress)
        end

        % Value changed function: SpatialFilteringCheckBox
        function SpatialFilteringCheckBoxValueChanged(app, event)
            app.modification_setting = read_modification_setting(app);
            update_interface(app,app.modification_setting);
        end

        % Value changed function: SF_FilterTypeDropDown
        function SF_FilterTypeDropDownValueChanged(app, event)
            app.modification_setting = read_modification_setting(app);
            update_interface(app,app.modification_setting);
        end

        % Value changed function: SF_FilterMethodDropDown
        function SF_FilterMethodDropDownValueChanged(app, event)
            app.modification_setting = read_modification_setting(app);
            update_interface(app,app.modification_setting);
        end

        % Value changed function: TemporalFilteringCheckBox
        function TemporalFilteringCheckBoxValueChanged(app, event)
            app.modification_setting = read_modification_setting(app);
            update_interface(app,app.modification_setting);
        end

        % Value changed function: TF_FilterTypeDropDown
        function TF_FilterTypeDropDownValueChanged(app, event)
            app.modification_setting = read_modification_setting(app);
            update_interface(app,app.modification_setting);
        end

        % Value changed function: TF_FilterMethodDropDown
        function TF_FilterMethodDropDownValueChanged(app, event)
            app.modification_setting = read_modification_setting(app);
            update_interface(app,app.modification_setting);
        end

        % Value changed function: OrientationFilteringCheckBox
        function OrientationFilteringCheckBoxValueChanged(app, event)
            if app.OrientationFilteringCheckBox.Value
                app.PhaseScrambleCheckBox.Value = 0;
            end
            app.modification_setting = read_modification_setting(app);
            update_interface(app,app.modification_setting);
        end

        % Value changed function: PhaseScrambleCheckBox
        function PhaseScrambleCheckBoxValueChanged(app, event)
            if app.PhaseScrambleCheckBox.Value
                app.OrientationFilteringCheckBox.Value = 0;
            end

            app.modification_setting = read_modification_setting(app);
            update_interface(app,app.modification_setting);
        end

        % Value changed function: FrequencyRangeDropDown
        function FrequencyRangeDropDownValueChanged(app, event)
            app.modification_setting = read_modification_setting(app);
            update_interface(app,app.modification_setting);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                app.main_app.enable_screen_info_edit
                delete(app.preview_app);
                delete(app.information_app);
            catch
            end
            delete(app)


        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.information_app = information("Modification.html");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.StimuliModificationLabel = uilabel(app.GridLayout);
            app.StimuliModificationLabel.Text = 'Stimuli Modification';
            app.ChooseMaskEditField = uieditfield(app.GridLayout, 'text');
            app.ChooseMaskEditField.Editable = 'off';
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ChooseStimuliButton = uibutton(app.GridLayout, 'push');
            app.ChooseStimuliButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseStimuliButtonPushed, true);
            app.ChooseStimuliButton.Text = 'Choose Stimuli';
            app.PhaseScramblePanel = uipanel(app.GridLayout);
            app.PhaseScramblePanel.Title = 'Phase Scramble';
            app.GridLayout5 = uigridlayout(app.PhaseScramblePanel);
            app.PS_LowCutoffEditField = uieditfield(app.GridLayout5, 'numeric');
            app.PS_LowCutoffEditField.Limits = [0 Inf];
            app.CutoffcpdLabel_3 = uilabel(app.GridLayout5);
            app.CutoffcpdLabel_3.Text = 'Cutoff (cpd)';
            app.PS_HighCutoffEditField = uieditfield(app.GridLayout5, 'numeric');
            app.PS_HighCutoffEditField.Limits = [0 Inf];
            app.ScramblingIndexEditField = uieditfield(app.GridLayout5, 'numeric');
            app.ScramblingIndexEditField.Limits = [0 1];
            app.ScramblingIndexEditField.Value = 1;
            app.ScramblingIndexEditFieldLabel = uilabel(app.GridLayout5);
            app.ScramblingIndexEditFieldLabel.Text = 'Scrambling Index';
            app.FrequencyRangeDropDownLabel = uilabel(app.GridLayout5);
            app.FrequencyRangeDropDownLabel.Text = 'Frequency Range';
            app.FrequencyRangeDropDown = uidropdown(app.GridLayout5);
            app.FrequencyRangeDropDown.Items = {'All', 'Low-pass', 'High-pass', 'Band-pass'};
            app.FrequencyRangeDropDown.ValueChangedFcn = createCallbackFcn(app, @FrequencyRangeDropDownValueChanged, true);
            app.FrequencyRangeDropDown.Value = 'All';
            app.SpatialPanel = uipanel(app.GridLayout);
            app.SpatialPanel.Title = 'Spatial';
            app.GridLayout2 = uigridlayout(app.SpatialPanel);
            app.SF_OrderEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SF_OrderEditField.Limits = [1 Inf];
            app.SF_OrderEditField.Value = 1;
            app.OrderEditFieldLabel = uilabel(app.GridLayout2);
            app.OrderEditFieldLabel.Text = 'Order';
            app.FilterMethodDropDownLabel = uilabel(app.GridLayout2);
            app.FilterMethodDropDownLabel.Text = 'Filter Method';
            app.SF_FilterMethodDropDown = uidropdown(app.GridLayout2);
            app.SF_FilterMethodDropDown.Items = {'Log Gaussian', 'Gaussian', 'Butterworth', 'Ideal'};
            app.SF_FilterMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SF_FilterMethodDropDownValueChanged, true);
            app.SF_FilterMethodDropDown.Value = 'Log Gaussian';
            app.FilterTypeDropDownLabel = uilabel(app.GridLayout2);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.SF_FilterTypeDropDown = uidropdown(app.GridLayout2);
            app.SF_FilterTypeDropDown.Items = {'Low-pass', 'High-pass', 'Band-pass'};
            app.SF_FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @SF_FilterTypeDropDownValueChanged, true);
            app.SF_FilterTypeDropDown.Value = 'Low-pass';
            app.CutoffcpdLabel_4 = uilabel(app.GridLayout2);
            app.CutoffcpdLabel_4.Text = 'Cutoff (cpd)';
            app.SF_LowCutoffEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SF_LowCutoffEditField.Limits = [0 Inf];
            app.SF_HighCutoffEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SF_HighCutoffEditField.Limits = [0 Inf];
            app.SpatialFilteringCheckBox = uicheckbox(app.GridLayout);
            app.SpatialFilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @SpatialFilteringCheckBoxValueChanged, true);
            app.SpatialFilteringCheckBox.Text = 'Spatial Filtering';
            app.TemporalFilteringCheckBox = uicheckbox(app.GridLayout);
            app.TemporalFilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @TemporalFilteringCheckBoxValueChanged, true);
            app.TemporalFilteringCheckBox.Text = 'Temporal Filtering';
            app.OrientationFilteringCheckBox = uicheckbox(app.GridLayout);
            app.OrientationFilteringCheckBox.ValueChangedFcn = createCallbackFcn(app, @OrientationFilteringCheckBoxValueChanged, true);
            app.OrientationFilteringCheckBox.Text = 'Orientation Filtering ';
            app.PhaseScrambleCheckBox = uicheckbox(app.GridLayout);
            app.PhaseScrambleCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaseScrambleCheckBoxValueChanged, true);
            app.PhaseScrambleCheckBox.Text = 'Phase Scramble';
            app.TemporalPanel = uipanel(app.GridLayout);
            app.TemporalPanel.Title = 'Temporal';
            app.GridLayout2_3 = uigridlayout(app.TemporalPanel);
            app.TF_LowCutoffEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.TF_LowCutoffEditField.Limits = [0 Inf];
            app.CutoffcpsLabel = uilabel(app.GridLayout2_3);
            app.CutoffcpsLabel.Text = 'Cutoff (cps)';
            app.TF_HighCutoffEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.TF_HighCutoffEditField.Limits = [0 Inf];
            app.TF_OrderEditField = uieditfield(app.GridLayout2_3, 'numeric');
            app.TF_OrderEditField.Limits = [1 Inf];
            app.TF_OrderEditField.Value = 1;
            app.OrderEditField_3Label = uilabel(app.GridLayout2_3);
            app.OrderEditField_3Label.Text = 'Order';
            app.FilterMethodDropDown_2Label = uilabel(app.GridLayout2_3);
            app.FilterMethodDropDown_2Label.Text = 'Filter Method';
            app.TF_FilterMethodDropDown = uidropdown(app.GridLayout2_3);
            app.TF_FilterMethodDropDown.Items = {'Log Gaussian', 'Gaussian', 'Butterworth', 'Ideal'};
            app.TF_FilterMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @TF_FilterMethodDropDownValueChanged, true);
            app.TF_FilterMethodDropDown.Value = 'Log Gaussian';
            app.FilterTypeDropDown_2Label = uilabel(app.GridLayout2_3);
            app.FilterTypeDropDown_2Label.Text = 'Filter Type';
            app.TF_FilterTypeDropDown = uidropdown(app.GridLayout2_3);
            app.TF_FilterTypeDropDown.Items = {'Low-pass', 'High-pass', 'Band-pass'};
            app.TF_FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TF_FilterTypeDropDownValueChanged, true);
            app.TF_FilterTypeDropDown.Value = 'Low-pass';
            app.OrientationPanel = uipanel(app.GridLayout);
            app.OrientationPanel.Title = 'Orientation';
            app.GridLayout4 = uigridlayout(app.OrientationPanel);
            app.OrientationdegreeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.OrientationdegreeEditFieldLabel = uilabel(app.GridLayout4);
            app.OrientationdegreeEditFieldLabel.Text = 'Orientation (degree)';
            app.SigmadegreeEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SigmadegreeEditField.Limits = [0.1 Inf];
            app.SigmadegreeEditField.Value = 1;
            app.SigmadegreeEditFieldLabel = uilabel(app.GridLayout4);
            app.SigmadegreeEditFieldLabel.Text = 'Sigma (degree)';
            app.RMSContrastLabel = uilabel(app.GridLayout);
            app.RMSContrastLabel.Text = 'RMS Contrast';
            app.RMSContrastEditField = uieditfield(app.GridLayout, 'numeric');
            app.RMSContrastEditField.Limits = [0 1];
            app.RMSContrastEditField.Value = 0.15;
            app.MeanLuminanceEditFieldLabel = uilabel(app.GridLayout);
            app.MeanLuminanceEditFieldLabel.Text = 'Mean Luminance';
            app.MeanLuminanceEditField = uieditfield(app.GridLayout, 'numeric');
            app.MeanLuminanceEditField.Limits = [0 1];
            app.MeanLuminanceEditField.Value = 0.5;
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = modification(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end