classdef preview < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        SaveIndividualMasksButton    matlab.ui.control.Button
        Slider                       matlab.ui.control.Slider
        HelpButton                   matlab.ui.control.Button
        SaveMPEG4VideoButton         matlab.ui.control.Button
        SavematFileButton            matlab.ui.control.Button
        PageNumberEditField          matlab.ui.control.EditField
        InformationPanel             matlab.ui.container.Panel
        InformationGridLayout        matlab.ui.container.GridLayout
        DefaultedgedetectionmethodcannyLabel  matlab.ui.control.Label
        EntropyEditField             matlab.ui.control.NumericEditField
        EntropyEditFieldLabel        matlab.ui.control.Label
        EdgeDensityEditField         matlab.ui.control.NumericEditField
        EdgeDensityEditFieldLabel    matlab.ui.control.Label
        MeanLuminanceEditField       matlab.ui.control.NumericEditField
        MeanLuminanceEditFieldLabel  matlab.ui.control.Label
        RMSContrastEditField         matlab.ui.control.NumericEditField
        RMSContrastEditFieldLabel    matlab.ui.control.Label
        TotalFramesEditField         matlab.ui.control.NumericEditField
        TotalFramesEditFieldLabel    matlab.ui.control.Label
        HeightdegreeofvisualangleEditField  matlab.ui.control.NumericEditField
        HeightdegreeofvisualangleEditFieldLabel  matlab.ui.control.Label
        WidthdegreeofvisualanlgeEditField  matlab.ui.control.NumericEditField
        WidthdegreeofvisualanlgeEditFieldLabel  matlab.ui.control.Label
        PlayButton                   matlab.ui.control.Button
        PreviewLabel                 matlab.ui.control.Label
        PauseButton                  matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        main_app
        calling_app
        information_app

        current_page
        pause_status

        bw_edges
        stimuli
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, main_app, calling_app, stimuli)
            app.main_app = main_app;
            app.calling_app = calling_app;
            app.stimuli = stimuli;

            imshow(app.stimuli.stimuli_array(:,:,:,1),"Parent",app.UIAxes)

            if isfield(app.stimuli,'individual_masks')
                app.SaveIndividualMasksButton.Enable = 1;
            else
                app.SaveIndividualMasksButton.Enable = 0;
            end

            app.WidthdegreeofvisualanlgeEditField.Value = stimuli.stimuli_property.stimuli_width_degree;
            app.HeightdegreeofvisualangleEditField.Value =  stimuli.stimuli_property.stimuli_height_degree;
            app.TotalFramesEditField.Value =  stimuli.stimuli_property.total_frames;
            app.RMSContrastEditField.Value =  stimuli.stimuli_property.rms_contrast;
            app.MeanLuminanceEditField.Value =  stimuli.stimuli_property.mean_luminance;
            app.EntropyEditField.Value =  stimuli.stimuli_property.entropy;
            app.EdgeDensityEditField.Value =  stimuli.stimuli_property.edge_density;

            if stimuli.stimuli_property.total_frames == 1
                app.Slider.Enable = "off";
                app.PlayButton.Enable = "off";
                app.PauseButton.Enable = "off";
                app.PageNumberEditField.Value = "1/1";
                app.SaveMPEG4VideoButton.Enable= 0;
            else
                app.current_page = 1;
                app.PageNumberEditField.Value = app.current_page + "/" + app.stimuli.stimuli_property.total_frames;
                app.Slider.Limits = [1, app.stimuli.stimuli_property.total_frames];
                %                 app.Slider.MajorTicksMode = 'auto';
                tick_interval = max(1,round(app.stimuli.stimuli_property.total_frames/20));
                app.Slider.MajorTicks = 1:tick_interval:app.stimuli.stimuli_property.total_frames;
                app.Slider.MajorTickLabels = string(1:tick_interval:app.stimuli.stimuli_property.total_frames);
                app.Slider.Value = app.current_page;
            end


            disableDefaultInteractivity(app.UIAxes);

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.pause_status = false;
            if app.current_page == app.stimuli.stimuli_property.total_frames
                app.current_page = 1;
            end
            for i = app.current_page:app.stimuli.stimuli_property.total_frames
                try
                    imshow(app.stimuli.stimuli_array(:,:,:,i), 'Parent', app.UIAxes);   % <--- use the Parent property
                    drawnow
                    app.Slider.Value = i;
                    app.current_page = i;
                    app.PageNumberEditField.Value = app.current_page + "/" + app.stimuli.stimuli_property.total_frames;
                    pause(1/app.stimuli.screen_info.refresh_rate);
                    if app.pause_status
                        break
                    end
                    %break loop when error occurs(i.e when preview app was closed)
                catch
                    break
                end
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.pause_status = true;
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            app.current_page = floor(event.Value);
            app.PageNumberEditField.Value = app.current_page + "/" + app.stimuli.stimuli_property.total_frames;
            imshow(app.stimuli.stimuli_array(:,:,:,app.current_page),'parent',app.UIAxes);

        end

        % Button pushed function: SavematFileButton
        function SavematFileButtonPushed(app, event)
            app.pause_status = true;
            stimuli = app.stimuli;
            uisave('stimuli','cfs_crafter_mask.mat');
            uialert(app.UIFigure,"File Saved!","Finish",'icon','success')
        end

        % Button pushed function: SaveMPEG4VideoButton
        function SaveMPEG4VideoButtonPushed(app, event)
            app.pause_status = true;


            [file,path] = uiputfile('*.mp4','Save MPEG-4 Video','cfs_crafter_mask.mp4');
            if isequal(file,0) || isequal(path,0)
                return
            else
                outputVideo = VideoWriter(fullfile(path,file),'MPEG-4');
                outputVideo.FrameRate = app.stimuli.screen_info.refresh_rate;
                open(outputVideo)

                app.stimuli.stimuli_array = double(app.stimuli.stimuli_array);

                for i = 1: app.stimuli.stimuli_property.total_frames
                    img = app.stimuli.stimuli_array(:,:,:,i);
                    writeVideo(outputVideo,img);
                end
                close(outputVideo)

            end

            uialert(app.UIFigure,"File Saved!","Finish",'icon','success');
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.pause_status = true;
            app.information_app = information("Preview.html");
        end

        % Button pushed function: SaveIndividualMasksButton
        function SaveIndividualMasksButtonPushed(app, event)
            app.pause_status = true;
            folder = uigetdir;

            if isequal(folder,0)
                return
            else
                if app.stimuli.stimuli_property.total_frames == 1
                    filename = ['cfs_crafter_image','.jpeg'];
                    imwrite(app.stimuli.stimuli_array,fullfile(folder,filename))
                else
                    [~,~,~,n_image] = size(app.stimuli.individual_masks);
                    for i = 1:n_image
                        filename = ['cfs_crafter_',num2str(i),'.jpeg'];
                        imwrite(app.stimuli.individual_masks(:,:,:,i),fullfile(folder,filename))
                    end
                end
            end
            uialert(app.UIFigure,"File Saved!","Finish",'icon','success');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PauseButton = uibutton(app.GridLayout, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.PreviewLabel = uilabel(app.GridLayout);
            app.PreviewLabel.Text = 'Preview';
            app.PlayButton = uibutton(app.GridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.InformationPanel = uipanel(app.GridLayout);
            app.InformationPanel.Title = 'Information';
            app.InformationGridLayout = uigridlayout(app.InformationPanel);
            app.WidthdegreeofvisualanlgeEditFieldLabel = uilabel(app.InformationGridLayout);
            app.WidthdegreeofvisualanlgeEditFieldLabel.Text = 'Width (degree of visual anlge)';
            app.WidthdegreeofvisualanlgeEditField = uieditfield(app.InformationGridLayout, 'numeric');
            app.WidthdegreeofvisualanlgeEditField.ValueDisplayFormat = '%.2f';
            app.WidthdegreeofvisualanlgeEditField.Editable = 'off';
            app.HeightdegreeofvisualangleEditFieldLabel = uilabel(app.InformationGridLayout);
            app.HeightdegreeofvisualangleEditFieldLabel.Text = 'Height (degree of visual angle)';
            app.HeightdegreeofvisualangleEditField = uieditfield(app.InformationGridLayout, 'numeric');
            app.HeightdegreeofvisualangleEditField.ValueDisplayFormat = '%.2f';
            app.HeightdegreeofvisualangleEditField.Editable = 'off';
            app.TotalFramesEditFieldLabel = uilabel(app.InformationGridLayout);
            app.TotalFramesEditFieldLabel.Text = 'Total Frames';
            app.TotalFramesEditField = uieditfield(app.InformationGridLayout, 'numeric');
            app.TotalFramesEditField.Editable = 'off';
            app.RMSContrastEditFieldLabel = uilabel(app.InformationGridLayout);
            app.RMSContrastEditFieldLabel.Text = 'RMS Contrast';
            app.RMSContrastEditField = uieditfield(app.InformationGridLayout, 'numeric');
            app.RMSContrastEditField.ValueDisplayFormat = '%.2f';
            app.RMSContrastEditField.Editable = 'off';
            app.MeanLuminanceEditFieldLabel = uilabel(app.InformationGridLayout);
            app.MeanLuminanceEditFieldLabel.Text = 'Mean Luminance';
            app.MeanLuminanceEditField = uieditfield(app.InformationGridLayout, 'numeric');
            app.MeanLuminanceEditField.ValueDisplayFormat = '%.2f';
            app.MeanLuminanceEditField.Editable = 'off';
            app.EdgeDensityEditFieldLabel = uilabel(app.InformationGridLayout);
            app.EdgeDensityEditFieldLabel.Text = 'Edge Density';
            app.EdgeDensityEditField = uieditfield(app.InformationGridLayout, 'numeric');
            app.EdgeDensityEditField.ValueDisplayFormat = '%.2f';
            app.EdgeDensityEditField.Editable = 'off';
            app.EntropyEditFieldLabel = uilabel(app.InformationGridLayout);
            app.EntropyEditFieldLabel.Text = 'Entropy';
            app.EntropyEditField = uieditfield(app.InformationGridLayout, 'numeric');
            app.EntropyEditField.ValueDisplayFormat = '%.2f';
            app.EntropyEditField.Editable = 'off';
            app.DefaultedgedetectionmethodcannyLabel = uilabel(app.InformationGridLayout);
            app.DefaultedgedetectionmethodcannyLabel.Text = '**Default edge detection method: canny';
            app.PageNumberEditField = uieditfield(app.GridLayout, 'text');
            app.SavematFileButton = uibutton(app.GridLayout, 'push');
            app.SavematFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavematFileButtonPushed, true);
            app.SavematFileButton.Text = 'Save .mat File';
            app.SaveMPEG4VideoButton = uibutton(app.GridLayout, 'push');
            app.SaveMPEG4VideoButton.ButtonPushedFcn = createCallbackFcn(app, @SaveMPEG4VideoButtonPushed, true);
            app.SaveMPEG4VideoButton.Text = 'Save MPEG-4 Video';
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.Slider = uislider(app.GridLayout);
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.SaveIndividualMasksButton = uibutton(app.GridLayout, 'push');
            app.SaveIndividualMasksButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIndividualMasksButtonPushed, true);
            app.SaveIndividualMasksButton.Text = 'Save Individual Masks';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preview(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end