classdef trace < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        HelpButton               matlab.ui.control.Button
        StartTracingButton       matlab.ui.control.Button
        ClearAllButton           matlab.ui.control.Button
        DeleteButton_2           matlab.ui.control.Button
        DeleteButton_5           matlab.ui.control.Button
        DeleteButton_4           matlab.ui.control.Button
        DeleteButton_3           matlab.ui.control.Button
        DeleteButton_1           matlab.ui.control.Button
        ChooseSourceImageButton  matlab.ui.control.Button
        SourceImageEditField     matlab.ui.control.EditField
        ObjectFaceTracingLabel   matlab.ui.control.Label
        FinishButton             matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        object_5_UIAxes          matlab.ui.control.UIAxes
        object_4_UIAxes          matlab.ui.control.UIAxes
        object_3_UIAxes          matlab.ui.control.UIAxes
        object_2_UIAxes          matlab.ui.control.UIAxes
        object_1_UIAxes          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        calling_app
        information_app

        roi
        source_image
        current_object_no
        image_handle
        object_axes
        delete_buttons


    end

    methods (Access = private)

        function update_interface(app)
            app.current_object_no =  length(app.calling_app.traced_object.object);

            for i = 1:app.current_object_no
                app.delete_buttons(i).Enable = "on";
                imshow(app.calling_app.traced_object.object{i},'parent',app.object_axes(i));
            end

            for i = (app.current_object_no+1):5

                app.delete_buttons(i).Enable = "off";

                imshow(ones(100),"Parent",app.object_axes(i));
            end

            if app.current_object_no ~= 5
                app.ChooseSourceImageButton.Enable = 'on';
            end

        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, creation_app)

            app.calling_app = creation_app;
            disableDefaultInteractivity(app.UIAxes);
            app.current_object_no = 0;

            app.calling_app.traced_object = struct('type',[],'object',[]);
            app.source_image = [];


            app.object_axes = [app.object_1_UIAxes, app.object_2_UIAxes, app.object_3_UIAxes, app.object_4_UIAxes, app.object_5_UIAxes];
            app.delete_buttons =  [app.DeleteButton_1, app.DeleteButton_2, app.DeleteButton_3, app.DeleteButton_4, app.DeleteButton_5];

            app.update_interface
        end

        % Button pushed function: ChooseSourceImageButton
        function ChooseSourceImageButtonPushed(app, event)
            [file,path] = uigetfile({'*.jpeg;*.jpg;*.png'});

            if file == 0
                return
            end

            app.SourceImageEditField.Value = fullfile(path,file);

            app.source_image = imread(app.SourceImageEditField.Value);

            app.source_image = im2double(app.source_image);

        end

        % Button pushed function: StartTracingButton
        function StartTracingButtonPushed(app, event)
            if isempty(app.SourceImageEditField.Value)
                errordlg("\fontsize{11}No File!","File Error",struct('Interpreter','TeX','WindowStyle','Modal'))
                return
            end

            if app.current_object_no == 5
                errordlg("\fontsize{11}Maximum allowed 5 objects!","Operation Error",struct('Interpreter','TeX','WindowStyle','Modal'))
                return
            end


            app.image_handle = imshow(app.source_image,"parent",app.UIAxes);

            switch app.calling_app.stimuli_setting.traced_item_type
                case "Face"
                    app.roi = images.roi.Ellipse(app.UIAxes,"StripeColor","y",'Deletable',true);

                case "Object"
                    app.roi = images.roi.AssistedFreehand(app.image_handle,"Closed",true,"Deletable",true,"StripeColor",'y',"Smoothing",10);

            end


            draw(app.roi);


            try
                wait(app.roi);
                if isvalid(app.roi)
                    if isempty(app.roi.Position)
                        return
                    else


                        app.current_object_no = length(app.calling_app.traced_object.object) + 1;
                        mask = createMask(app.roi);

                        if app.calling_app.stimuli_setting.traced_item_type == "Object"
                            mask = imgaussfilt(double(mask),10);
                            mask = mask > 0.5;
                        end

                        [row,col] = find(mask == 1);

                        bounding_box(1) = min(col);
                        bounding_box(2) = min(row);
                        bounding_box(3) = max(col) - bounding_box(1);
                        bounding_box(4) = max(row) - bounding_box(2);


                        object = app.source_image .* mask;

                        app.calling_app.traced_object.object{app.current_object_no} = imcrop(object,bounding_box);

                        app.calling_app.traced_object.paste_index{app.current_object_no} = imcrop(mask,bounding_box);

                        imshow(app.calling_app.traced_object.object{app.current_object_no},"parent",app.UIAxes);
                    end
                else
                    return
                end
                app.update_interface;
            catch
            end


        end

        % Button pushed function: DeleteButton_1
        function DeleteButton_1Pushed(app, event)
            app.calling_app.traced_object.object(1) = [];
            app.calling_app.traced_object.paste_index(1) = [];
            app.update_interface;
        end

        % Button pushed function: DeleteButton_2
        function DeleteButton_2Pushed(app, event)

            app.calling_app.traced_object.object(2) = [];
            app.calling_app.traced_object.paste_index(2) = [];
            app.update_interface;
        end

        % Button pushed function: DeleteButton_3
        function DeleteButton_3Pushed(app, event)
            app.calling_app.traced_object.object(3) = [];
            app.calling_app.traced_object.paste_index(3) = [];
            app.update_interface;
        end

        % Button pushed function: DeleteButton_4
        function DeleteButton_4Pushed(app, event)
            app.calling_app.traced_object.object(4) = [];
            app.calling_app.traced_object.paste_index(4) = [];
            app.update_interface;
        end

        % Button pushed function: DeleteButton_5
        function DeleteButton_5Pushed(app, event)
            app.calling_app.traced_object.object(5) = [];
            app.calling_app.traced_object.paste_index(5) = [];
            app.update_interface;
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            if exist("app.roi","var")
                delete(app.roi)
            end

            cla(app.UIAxes,"reset");
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Box = 'on';
            app.SourceImageEditField.Value = '';
            app.source_image = [];
            app.calling_app.traced_object = struct('type',[],'object',[]);
            app.current_object_no = 0;
            app.update_interface;


        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.calling_app.update_interface(app.calling_app.stimuli_setting)
            delete(app)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            app.information_app = information("Trace.html");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try
                delete(app.information_app)
            catch
            end

            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.object_1_UIAxes = uiaxes(app.GridLayout);
            app.object_1_UIAxes.XTick = [];
            app.object_1_UIAxes.YTick = [];
            app.object_2_UIAxes = uiaxes(app.GridLayout);
            app.object_2_UIAxes.XTick = [];
            app.object_2_UIAxes.YTick = [];
            app.object_3_UIAxes = uiaxes(app.GridLayout);
            app.object_3_UIAxes.XTick = [];
            app.object_3_UIAxes.YTick = [];
            app.object_4_UIAxes = uiaxes(app.GridLayout);
            app.object_4_UIAxes.XTick = [];
            app.object_4_UIAxes.YTick = [];
            app.object_5_UIAxes = uiaxes(app.GridLayout);
            app.object_5_UIAxes.XTick = [];
            app.object_5_UIAxes.YTick = [];
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.FinishButton = uibutton(app.GridLayout, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.ObjectFaceTracingLabel = uilabel(app.GridLayout);
            app.ObjectFaceTracingLabel.Text = 'Object/Face Tracing';
            app.SourceImageEditField = uieditfield(app.GridLayout, 'text');
            app.SourceImageEditField.Editable = 'off';
            app.ChooseSourceImageButton = uibutton(app.GridLayout, 'push');
            app.ChooseSourceImageButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseSourceImageButtonPushed, true);
            app.ChooseSourceImageButton.Text = 'Choose Source Image';
            app.DeleteButton_1 = uibutton(app.GridLayout, 'push');
            app.DeleteButton_1.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_1Pushed, true);
            app.DeleteButton_1.Text = 'Delete';
            app.DeleteButton_3 = uibutton(app.GridLayout, 'push');
            app.DeleteButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_3Pushed, true);
            app.DeleteButton_3.Text = 'Delete';
            app.DeleteButton_4 = uibutton(app.GridLayout, 'push');
            app.DeleteButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_4Pushed, true);
            app.DeleteButton_4.Text = 'Delete';
            app.DeleteButton_5 = uibutton(app.GridLayout, 'push');
            app.DeleteButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_5Pushed, true);
            app.DeleteButton_5.Text = 'Delete';
            app.DeleteButton_2 = uibutton(app.GridLayout, 'push');
            app.DeleteButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteButton_2Pushed, true);
            app.DeleteButton_2.Text = 'Delete';
            app.ClearAllButton = uibutton(app.GridLayout, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.StartTracingButton = uibutton(app.GridLayout, 'push');
            app.StartTracingButton.ButtonPushedFcn = createCallbackFcn(app, @StartTracingButtonPushed, true);
            app.StartTracingButton.Text = 'Start Tracing';
            app.HelpButton = uibutton(app.GridLayout, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trace(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end